classdef ShockTubePostProcessorWithPlot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ShockTubePostProcessorUIFigure  matlab.ui.Figure
        PCB2Label                       matlab.ui.control.Label
        PCB2CheckBox                    matlab.ui.control.CheckBox
        PCB3CheckBox                    matlab.ui.control.CheckBox
        PCB4CheckBox                    matlab.ui.control.CheckBox
        PCB5CheckBox                    matlab.ui.control.CheckBox
        PCB6CheckBox                    matlab.ui.control.CheckBox
        PCB7CheckBox                    matlab.ui.control.CheckBox
        UismsTextArea                   matlab.ui.control.TextArea
        UismsTextAreaLabel              matlab.ui.control.Label
        P5PaTextArea                    matlab.ui.control.TextArea
        P5PaTextAreaLabel               matlab.ui.control.Label
        T5KTextArea                     matlab.ui.control.TextArea
        T5KTextAreaLabel                matlab.ui.control.Label
        LoadRawDataPlotUisButton        matlab.ui.control.Button
        PlotCh7Button                   matlab.ui.control.Button
        PlotCh6Button                   matlab.ui.control.Button
        PlotCh5Button                   matlab.ui.control.Button
        PlotCh4Button                   matlab.ui.control.Button
        PlotCh3Button                   matlab.ui.control.Button
        PlotCh2Button                   matlab.ui.control.Button
        PlotCh1Button                   matlab.ui.control.Button
        PlotCh0Button                   matlab.ui.control.Button
        State5AssumeButtonGroup         matlab.ui.container.ButtonGroup
        isobaricButton                  matlab.ui.control.RadioButton
        isochoricButton                 matlab.ui.control.RadioButton
        isentropicButton                matlab.ui.control.RadioButton
        NASAFileTextArea                matlab.ui.control.TextArea
        NASAFileTextAreaLabel           matlab.ui.control.Label
        FROSHSolnButtonGroup            matlab.ui.container.ButtonGroup
        FFButton                        matlab.ui.control.RadioButton
        FEButton                        matlab.ui.control.RadioButton
        EEButton                        matlab.ui.control.RadioButton
        dateTextArea                    matlab.ui.control.TextArea
        dateTextAreaLabel               matlab.ui.control.Label
        inEditField_6                   matlab.ui.control.EditField
        inEditField_6Label              matlab.ui.control.Label
        Port8Label                      matlab.ui.control.Label
        Port7Label                      matlab.ui.control.Label
        Port6Label                      matlab.ui.control.Label
        Port5Label                      matlab.ui.control.Label
        Port4Label                      matlab.ui.control.Label
        inEditField_7                   matlab.ui.control.EditField
        inEditField_7Label              matlab.ui.control.Label
        inEditField_5                   matlab.ui.control.EditField
        inEditField_5Label              matlab.ui.control.Label
        inEditField_4                   matlab.ui.control.EditField
        inEditField_4Label              matlab.ui.control.Label
        inEditField_3                   matlab.ui.control.EditField
        inEditField_3Label              matlab.ui.control.Label
        inEditField_2                   matlab.ui.control.EditField
        inEditField_2Label              matlab.ui.control.Label
        inEditField                     matlab.ui.control.EditField
        Label                           matlab.ui.control.Label
        RunFROSHButton                  matlab.ui.control.Button
        DrivenGasTable                  matlab.ui.control.Table
        DriverGasTable                  matlab.ui.control.Table
        Path                            matlab.ui.control.Label
        PathButton                      matlab.ui.control.Button
        DrivenGasLabel                  matlab.ui.control.Label
        DriverGasLabel                  matlab.ui.control.Label
        SensorFreqHzLabel               matlab.ui.control.Label
        DiagnosticLabel                 matlab.ui.control.Label
        usedLabel                       matlab.ui.control.Label
        AcqTimemsTextArea               matlab.ui.control.TextArea
        AcqTimemsTextAreaLabel          matlab.ui.control.Label
        Ch7_label                       matlab.ui.control.Label
        Ch7_used                        matlab.ui.control.CheckBox
        Ch7_diagnostic                  matlab.ui.control.DropDown
        Ch7_sensor_freq                 matlab.ui.control.EditField
        Ch6_label                       matlab.ui.control.Label
        Ch6_used                        matlab.ui.control.CheckBox
        Ch6_diagnostic                  matlab.ui.control.DropDown
        Ch6_sensor_freq                 matlab.ui.control.EditField
        Ch5_label                       matlab.ui.control.Label
        Ch5_used                        matlab.ui.control.CheckBox
        Ch5_diagnostic                  matlab.ui.control.DropDown
        Ch5_sensor_freq                 matlab.ui.control.EditField
        Ch4_label                       matlab.ui.control.Label
        Ch4_used                        matlab.ui.control.CheckBox
        Ch4_diagnostic                  matlab.ui.control.DropDown
        Ch4_sensor_freq                 matlab.ui.control.EditField
        Ch3_label                       matlab.ui.control.Label
        Ch3_used                        matlab.ui.control.CheckBox
        Ch3_diagnostic                  matlab.ui.control.DropDown
        Ch3_sensor_freq                 matlab.ui.control.EditField
        Ch2_label                       matlab.ui.control.Label
        Ch2_used                        matlab.ui.control.CheckBox
        Ch2_diagnostic                  matlab.ui.control.DropDown
        Ch2_sensor_freq                 matlab.ui.control.EditField
        Ch1_label                       matlab.ui.control.Label
        Ch1_used                        matlab.ui.control.CheckBox
        Ch1_diagnostic                  matlab.ui.control.DropDown
        Ch1_sensor_freq                 matlab.ui.control.EditField
        Ch0_sensor_freq                 matlab.ui.control.EditField
        Ch0_diagnostic                  matlab.ui.control.DropDown
        Ch0_label                       matlab.ui.control.Label
        Ch0_used                        matlab.ui.control.CheckBox
        RunTextArea                     matlab.ui.control.TextArea
        RunTextAreaLabel                matlab.ui.control.Label
        TestTypeButtonGroup             matlab.ui.container.ButtonGroup
        NonreactingButton               matlab.ui.control.RadioButton
        ReactingButton                  matlab.ui.control.RadioButton
        PretriggermsTextArea            matlab.ui.control.TextArea
        PretriggermsTextAreaLabel       matlab.ui.control.Label
        DataPointsTextArea              matlab.ui.control.TextArea
        DataPointsTextAreaLabel         matlab.ui.control.Label
        OScopeFreqHzTextArea            matlab.ui.control.TextArea
        OScopeFreqHzTextAreaLabel       matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        UIAxes                          matlab.ui.control.UIAxes
    end


    properties (Access = private)
    end
    %Function Dependencies:
    %-----------------------------
    %CHON_MW.m
    %FrequencySwitchCase.m
    %GetFiles.m
    %manometry.m
    %MlappemissionTrace.m
    %Mlappfrosh_bias_V2.m
        %frosh.m
    %MlappLasTrace.m
    %MlappPressureTrace.
    %MlappShockVelocity.m
    %ParseElementString.m
    %PredictBifurcation.m

    %Future upgrades
    % ___________________________FROSH____________________________________
    % I have to run FROSH to get some channel plots because Urs is used as part of filtering scheme.....This seems like part of a bigger problem- I need some data to plot some things, but not other things.  I wish I could make this flexible enough to do what it can without the missing data, and only throw an error when the specific task I request doesn't have the necessary data, then allow me to calculate what I need as I need it. 
    % Output T2, P2,Mis, Mrs, and gammaOut from FROSH ( Incident and Reflected Shock Mach #s, and ratios of specific heats - see FROSH frontmatter comments
    % PRIORITY: Mlappfrosh_bias_V2 ("Run Frosh" button) takes a long time to run. Can I make it faster?
    % Plot from Mlappfrosh_bias_V2 ("Run Frosh" button) is a separate plot. Can we integrate it into the GUI plotting window?
    % Maybe add some radio buttons for which version of Frosh you want to
    % use: 1)FROSH, no Uncertainty, 2)FROSH with Uncertainty, 3)AeroFROSH, no Uncertainty, 4)AeroFROSH with Uncertainty
     
    % ___________________________Plots____________________________________
    % Uis plot does not include all desired elements. 
    % I want to be able to add plot elements to many of the plots in real-time.
    % I want to apply/play with filters then identify plot elements (incident, reflected shock, State 1, 2, 5)
    % I just want a lot more flexibility in plotting data. - plotting raw data, plotting quantities derived from raw data, plotting different data together, etc
     
    % ___________________Flexibility/Features_____________________________
    % Can we add a "Make this value Default" option to all user inputs?
    % easily add diagnostic functions to dropdown menu
    % Add a "Comments" box in GUI
    % Either make "Run Frosh" button work faster, or at least give it a progress bar.
    % PRIORITY: Add a button to write the current plot's data to a user-named excel file
    % PRIORITY: append all 'base' data to a database excel or CSV - preferablly lots of columns of data in a single row.
    % Add error messages to prompt user to push buttons in "correct" order
    % I'd like to clear all data and plots from current run and start analysis of the next run without closing and reopening the app.
    % User-selected number of PCBs, and allow any combination to be selected.
    % Driven Gas Table - Add more calculated mixture properties to driven gas table (gamma, chem name for mech, Nasa Params, etc
    % Driven Gas Table -Can we make Driven gas components dropdown menus, and adjust logic such that selecting "NA" in any field will cause the math to ignore that field?
    % Driven Gas Table - Need to work on capability to calculate for a liquid fuel added last.<-- I think I could do this by adding in a mixing tank selection and a radio button for liquid vs gaseous fuel.The radio button could swap out the gaseous fuel driven gas table for a liquid fuel driven gas table.  We could extend this to Aerosols(And AeroFrosh) as well.
      
    % ___________________Code Efficiency/Readability_____________________
    % PRIORITY: Diagnostic Switch Case is repeated 8 times. Need to make it a function
    % 'ShockData" Columns are still hard-coded. Need to change them to a variable
    % Ch. Frequency is still hard-coded.  Needs to change to user input.
     
    % ___________________For Mitch, Later_____________________________
    %1.d update frosh_bias.m to handle correllated, n-component mixtures - might require 1.e
    %1.e Use Monte Carlo analysis to determine contribution of correlated mixture uncertainties
    %3.c Xinitial is always known. Use this to plot X time-history?
    %3.d use all previous D/F data from the current day to plot I0 over time.
    %5.a figure out how to adjust IDT calculation parameters to eliminate matlab errors


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Physical constants
            assignin('base','WindowDiam',0.0127);%[meters] = 0.5in -Diameter of test section windows
            assignin('base','TubeDiameter',str2double(app.inEditField_6.Value)*0.0254); %[m]
            assignin('base','Bias_TubeDiameter',0.0000127); %[m]

            %Determine Acquisition time from Scope Frequency and # Data Points
            frequencyInputValue = str2double(app.OScopeFreqHzTextArea.Value);
            dataPointsInputValue = str2double(app.DataPointsTextArea.Value);
            if ~isnan(frequencyInputValue) && ~isnan(dataPointsInputValue)
                result = frequencyInputValue / dataPointsInputValue;

                app.AcqTimemsTextArea.Value = num2str(result);
            else
                app.AcqTimemsTextArea.Value = 'NAN';
            end

            %Driver Gas Table Defaults
            DriverGas1Frac=1.0;
            DriverGas2Frac=0.0;
            DriverGasUnityCheck=DriverGas1Frac+DriverGas2Frac;
            t1_data = {'Chem Formula', 'He', 'NA', 'NA'; 'Tank Press Rdg [Torr]', 'NA', 'NA', 'NA'; 'Mole Fraction', DriverGas1Frac, DriverGas2Frac, DriverGasUnityCheck};
            columnNames = {'Components', 'Gas 1', 'Gas 2', 'Check Calcs'};

            % Set the data and column names to the uitable component
            app.DriverGasTable.Data = t1_data;
            app.DriverGasTable.ColumnName = columnNames;
            app.DriverGasTable.CellEditCallback = @(src, event) DriverGasTableCellEdit (app, event);
            app.DriverGasTable.ColumnEditable = true;

            % Driven Gas Table Defaults
            %t2_data = {'Chem Formula', 'C10H18', 'O2', 'N2', 'Ar', 'Check';'Tank Pressure Rdg', 3, 33, 123, 900, 'NA'; 'Mole Fraction', 0.003, 0.033, 0.1, 0.863, 1.0};
            t2_data = {'Chem Formula', 'C2H4', 'O2', 'N2', 'Ar', 'Check';'Tank Press Rdg [Torr]', 32.72, 131, 500, 500, 'NA'; 'Mole Fraction', 0.0, 0.0, 0.0, 0.0, 'NA Enter Data!';'Mole Fraction Bias', 0.0, 0.0, 0.0, 0.0, 'NA';'MW', 0.0, 0.0, 0.0, 0.0, 'NA'};
            %t2_data = {'Chem Formula', 'CxHy', 'O2', 'N2', 'Ar', 'Check';'Tank Press Rdg [Torr]', 0.0, 0.0, 0.0, 0.0, 'NA'; 'Mole Fraction', 0.0, 0.0, 0.0, 0.0, 'NA Enter Data!';'Mole Fraction Bias', 0.0, 0.0, 0.0, 0.0, 'NA';'MW', 0.0, 0.0, 0.0, 0.0, 'NA'};
            % Create column names
            columnNames = {'Components', 'Fuel', 'Oxidizer', 'Inert', 'Inert', 'Check'};
            % Set the data and column names to the uitable component
            app.DrivenGasTable.Data = t2_data;
            app.DrivenGasTable.ColumnName = columnNames;
            app.DrivenGasTable.CellEditCallback = @(src, event) DrivenGasTableCellEdit (app, event);
            app.DrivenGasTable.ColumnEditable = true;


            Pmix=[0 app.DrivenGasTable.Data{7} app.DrivenGasTable.Data{12} app.DrivenGasTable.Data{17} app.DrivenGasTable.Data{22}];
            testGasSpec={app.DrivenGasTable.Data{6}, app.DrivenGasTable.Data{11}, app.DrivenGasTable.Data{16}, app.DrivenGasTable.Data{21}};
            [XtestGas, XtestGas_bias, MW] = manometry(Pmix',testGasSpec'); %Partial Pressure Bias Calclation
            app.DrivenGasTable.Data{8}=XtestGas(1); %X component 1
            app.DrivenGasTable.Data{13}=XtestGas(2);%X component 2
            app.DrivenGasTable.Data{18}=XtestGas(3);%X component 3
            app.DrivenGasTable.Data{23}=XtestGas(4);%X component 4
            DrivenGasUnityCheck=sum(XtestGas(:));
            app.DrivenGasTable.Data{9}=XtestGas_bias(1); %X Bias component 1
            app.DrivenGasTable.Data{14}=XtestGas_bias(2);%X Bias component 2
            app.DrivenGasTable.Data{19}=XtestGas_bias(3);%X Bias component 3
            app.DrivenGasTable.Data{24}=XtestGas_bias(4);%X Bias component 4
            app.DrivenGasTable.Data{10}=MW(1); %MW Bias component 1
            app.DrivenGasTable.Data{15}=MW(2);%MW Bias component 2
            app.DrivenGasTable.Data{20}=MW(3);%MW Bias component 3
            app.DrivenGasTable.Data{25}=MW(4);%MW Bias component 4
            if DrivenGasUnityCheck==1
                app.DrivenGasTable.Data{28}=DrivenGasUnityCheck;
                assignin('base','XtestGas',XtestGas)
                assignin('base','XtestGas_bias',XtestGas)
                assignin('base','testGasSpec',testGasSpec)
            else
                app.DrivenGasTable.Data{28}='Error!';
            end

            %Thermodynamic state initial selections
            %FROSH Solution Method
            if app.FFButton.Value==true
                solnmethod='FF';
            elseif app.EEButton.Value==true
                solnmethod='EE';
            else
                solnmethod='FE';
            end
            assignin('base','solnmethod',solnmethod)

            %Post-Shock State Assumption
            if app.isobaricButton.Value==true
                ThermoState='isobaric';
            elseif app.isochoricButton.Value==true
                ThermoState='isochoric';
            else
                ThermoState='isentropic';
            end
            assignin('base','ThermoState',ThermoState)

            %Reacting Vs Non-Reacting test  - This portion of code not yet used.
            if app.ReactingButton.Value == true
                Reacting='y';
            else
                Reacting='n';
            end
            assignin('base','Reacting',Reacting)

            %Assign number of counters 
            %***NOTE!*** You haven't connected the callbacks for these PCBXCheckBox values, so changing the checkbox value in the GUI does nothing. 
            numberOfPCBs=app.PCB2CheckBox.Value+app.PCB3CheckBox.Value+app.PCB4CheckBox.Value+app.PCB5CheckBox.Value+app.PCB6CheckBox.Value+app.PCB7CheckBox.Value;
            numberOfCounters=numberOfPCBs-1;
            disp(numberOfCounters)
            assignin('base','numberOfCounters',numberOfCounters)


        end

        % Button pushed function: PathButton
        function PathButtonPushed(app, event)
            [filename, pathname] = uigetfile('*.*', 'Select a file');
            if isequal(filename, 0)
                app.PathButton.Text= 'No file selected.';
            else
                fullFilePath = fullfile(pathname, filename);
                app.PathButton.Text = fullFilePath;
            end
        end

        % Callback function: OScopeFreqHzTextArea, OScopeFreqHzTextArea
        function ScopeFrequencyChanged(app, event)
            frequencyInputValue = str2double(app.OScopeFreqHzTextArea.Value);
            dataPointsInputValue = str2double(app.DataPointsTextArea.Value);
            if ~isnan(frequencyInputValue) && ~isnan(dataPointsInputValue)
                result = frequencyInputValue / dataPointsInputValue;

                app.AcqTimemsTextArea.Value = num2str(result);
            else
                app.AcqTimemsTextArea.Value = 'NAN';
            end
        end

        % Callback function: DataPointsTextArea, DataPointsTextArea
        function DataPointsChanged(app, event)
            frequencyInputValue = str2double(app.OScopeFreqHzTextArea.Value);
            dataPointsInputValue = str2double(app.DataPointsTextArea.Value);
            if ~isnan(frequencyInputValue) && ~isnan(dataPointsInputValue)
                result = frequencyInputValue / dataPointsInputValue;

                app.AcqTimemsTextArea.Value = num2str(result);
            else
                app.AcqTimemsTextArea.Value = 'NAN';
            end
        end

        % Cell edit callback: DriverGasTable
        function DriverGasTableCellEdit(app, event)
            if event.Indices(1) == 1 %If Driver Gas names are changed
                app.DriverGasTable.Data{event.Indices(1), event.Indices(2)} = event.NewData;
            elseif event.Indices(1) == 3%If Driver mole fractions are changed
                app.DriverGasTable.Data{event.Indices(1), event.Indices(2)} = event.NewData;
                DriverGas1Frac=app.DriverGasTable.Data{6};
                DriverGas2Frac=app.DriverGasTable.Data{9};
                DriverGasUnityCheck=DriverGas1Frac+DriverGas2Frac;
                if DriverGasUnityCheck==1
                    app.DriverGasTable.Data{12}=DriverGasUnityCheck;
                else
                    app.DriverGasTable.Data{12}='Error!';
                end
            else
                % If in the second row, revert the edit
                app.DriverGasTable.Data{event.Indices(1), event.Indices(2)} = event.PreviousData;
            end
        end

        % Value changed function: Ch0_diagnostic
        function Ch0_diagnosticValueChanged(app, event)
            [app.Ch0_sensor_freq.Value,Ch_Freq(1)]=FrequencySwitchCase(app.Ch0_diagnostic.Value);
        end

        % Callback function: RunFROSHButton, UIAxes
        function RunFROSHButtonPushed(app, event)
            T1_bias = 5.0; %MDH change 11/9/2023.  RTD is 0.15K, but it's on the outside wall of the test section.
            P1_bias = (evalin('base','P1'))*0.0012;
            [Tout, Pout, rhoOut, gammaOut, VsoundOut, Mout, Urs, phi] = frosh('Uis',evalin('base','Uis'),'T1',evalin('base','T1'),'P1',evalin('base','P1'),'testGasSpec',evalin('base','testGasSpec'),'XtestGas',evalin('base','XtestGas'),'solnMethod',evalin('base','solnmethod'),'nasaFile',app.NASAFileTextArea.Value{1});
            app.T5KTextArea.Value = num2str(Tout(2),'%.0f');
            app.P5PaTextArea.Value = num2str(Pout(2),'%.0f');
            [T2_bias, P2_bias, T5_bias, P5_bias, T5, P5_atm] = Mlappfrosh_bias_V2('Uis',evalin('base','Uis'),'Uis_bias',evalin('base','Uis_bias'),'T1',evalin('base','T1'),'T1_bias',T1_bias,'P1',evalin('base','P1'),'P1_bias',P1_bias,'testGasSpec',evalin('base','testGasSpec'),'XtestGas',evalin('base','XtestGas'),'XtestGas_bias',evalin('base','XtestGas_bias'),'solnMethod',evalin('base','solnmethod'),'nasaFile',app.NASAFileTextArea.Value{1});% [% % % % K atm]
            %Assign FROSH outputs to workspace
            assignin('base','Tout',Tout)
            assignin('base','Pout',Pout)
            assignin('base','rhoOut',rhoOut)
            assignin('base','gammaOut',gammaOut)
            assignin('base','VsoundOut',VsoundOut)
            assignin('base','Mout',Mout)
            assignin('base','Urs',Urs)
            %Assign BIAS outputs to workspace
            assignin('base','T2_bias',T2_bias)
            assignin('base','P2_bias',P2_bias)
            assignin('base','P5_bias',P5_bias)
            assignin('base','T5_bias',T5_bias)
            assignin('base','T5',T5)
            assignin('base','P5_atm',P5_atm)
%{
            %My first attempt at making the FROSH Bias bar graph in the
            app... I failed.
            cla(app.UIAxes) %Clears Axes if something was plotted previously
            b=barh(app.UIAxes,y,1);
            b(app.UIAxes,1).FaceColor='red';
            b(app.UIAxes,2).FaceColor='blue';
            b(app.UIAxes,3).FaceColor='black';
            %hold(app.UIAxes, 'on')
            %Add values to tips of bars (T5)
            if b(app.UIAxes,1).YEndPoints<0
                xtips1 = b(app.UIAxes,1).YEndPoints - 0.01;
            else
                xtips1 = b(app.UIAxes,1).YEndPoints + 0.01;
            end
            ytips1 = b(app.UIAxes,1).XEndPoints;
            labels1 = string(round(b(app.UIAxes,1).YData,2));
            text(xtips1,ytips1,labels1,'VerticalAlignment','middle')
            %Add values to tips of bars (P5)
            if b(app.UIAxes,2).YEndPoints<0
                xtips2 = b(app.UIAxes,2).YEndPoints - 0.01;
            else
                xtips2 = b(app.UIAxes,2).YEndPoints + 0.01;
            end
            ytips2 = b(app.UIAxes,2).XEndPoints;
            labels2 = string(round(b(app.UIAxes,2).YData,2));
            text(xtips2,ytips2,labels2,'VerticalAlignment','middle')
            %Add values to tips of bars (T5/P5)
            if b(app.UIAxes,3).YEndPoints<0
                xtips3 = b(app.UIAxes,3).YEndPoints - 0.01;
            else
                xtips3 = b(app.UIAxes,3).YEndPoints + 0.01;
            end
            ytips3 = b(app.UIAxes,3).XEndPoints;
            labels3 = string(round(b(app.UIAxes,3).YData,2));
            text(xtips3,ytips3,labels3,'VerticalAlignment','middle')
            set(app.UIAxes,gca,'YTick',1:numel(y))
            set(app.UIAxes,gca,'YTickLabel',{'Mole Fractions','NASA Coefficients','U_{is}','P_1','T_1'})
            xlabel(app.UIAxes,'Percent Change')
            legend(app.UIAxes,{'Percent Change in T_5','Percent Change in P_5','Percent Change in T_5/P_5'})
            %hold off
%}
        end

        % Value changed function: Ch1_diagnostic
        function Ch1_diagnosticValueChanged(app, event)
            [app.Ch1_sensor_freq.Value,Ch_Freq(2)]=FrequencySwitchCase(app.Ch1_diagnostic.Value);
        end

        % Value changed function: Ch2_diagnostic
        function Ch2_diagnosticValueChanged(app, event)
            [app.Ch2_sensor_freq.Value,Ch_Freq(3)]=FrequencySwitchCase(app.Ch2_diagnostic.Value);
        end

        % Value changed function: Ch3_diagnostic
        function Ch3_diagnosticValueChanged(app, event)
            [app.Ch3_sensor_freq.Value,Ch_Freq(4)]=FrequencySwitchCase(app.Ch3_diagnostic.Value);
        end

        % Value changed function: Ch4_diagnostic
        function Ch4_diagnosticValueChanged(app, event)
            [app.Ch4_sensor_freq.Value,Ch_Freq(5)]=FrequencySwitchCase(app.Ch4_diagnostic.Value);
        end

        % Value changed function: Ch5_diagnostic
        function Ch5_diagnosticValueChanged(app, event)
            [app.Ch5_sensor_freq.Value,Ch_Freq(6)]=FrequencySwitchCase(app.Ch5_diagnostic.Value);
        end

        % Value changed function: Ch6_diagnostic
        function Ch6_diagnosticValueChanged(app, event)
            [app.Ch6_sensor_freq.Value,Ch_Freq(7)]=FrequencySwitchCase(app.Ch6_diagnostic.Value);
        end

        % Value changed function: Ch7_diagnostic
        function Ch7_diagnosticValueChanged(app, event)
            [app.Ch7_sensor_freq.Value,Ch_Freq(8)]=FrequencySwitchCase(app.Ch7_diagnostic.Value);
        end

        % Callback function
        function RunTextAreaValueChanged(app, event)

        end

        % Cell edit callback: DrivenGasTable
        function DrivenGasTableCellEdit(app, event)
            if event.Indices(1) == 1 %If Driven Gas names are changed
                app.DrivenGasTable.Data{event.Indices(1), event.Indices(2)} = event.NewData;
                Pmix=[0 app.DrivenGasTable.Data{7} app.DrivenGasTable.Data{12} app.DrivenGasTable.Data{17} app.DrivenGasTable.Data{22}];
                testGasSpec={app.DrivenGasTable.Data{6}, app.DrivenGasTable.Data{11}, app.DrivenGasTable.Data{16}, app.DrivenGasTable.Data{21}};
                [XtestGas, XtestGas_bias, MW] = manometry(Pmix',testGasSpec'); %Partial Pressure Bias Calclation
                app.DrivenGasTable.Data{8}=XtestGas(1); %X component 1
                app.DrivenGasTable.Data{13}=XtestGas(2);%X component 2
                app.DrivenGasTable.Data{18}=XtestGas(3);%X component 3
                app.DrivenGasTable.Data{23}=XtestGas(4);%X component 4
                DrivenGasUnityCheck=sum(XtestGas(:));
                app.DrivenGasTable.Data{9}=XtestGas_bias(1); %X Bias component 1
                app.DrivenGasTable.Data{14}=XtestGas_bias(2);%X Bias component 2
                app.DrivenGasTable.Data{19}=XtestGas_bias(3);%X Bias component 3
                app.DrivenGasTable.Data{24}=XtestGas_bias(4);%X Bias component 4
                app.DrivenGasTable.Data{10}=MW(1); %MW Bias component 1
                app.DrivenGasTable.Data{15}=MW(2);%MW Bias component 2
                app.DrivenGasTable.Data{20}=MW(3);%MW Bias component 3
                app.DrivenGasTable.Data{25}=MW(4);%MW Bias component 4
                if DrivenGasUnityCheck==1
                    app.DrivenGasTable.Data{28}=DrivenGasUnityCheck;
                    assignin('base','XtestGas',XtestGas)
                    assignin('base','XtestGas_bias',XtestGas)
                    assignin('base','testGasSpec',testGasSpec)
                else
                    app.DrivenGasTable.Data{28}='Error!';
                end
            elseif event.Indices(1) == 2%If Driven gas Tank pressures mole fractions are changed
                app.DrivenGasTable.Data{event.Indices(1), event.Indices(2)} = event.NewData;
                Pmix=[0 app.DrivenGasTable.Data{7} app.DrivenGasTable.Data{12} app.DrivenGasTable.Data{17} app.DrivenGasTable.Data{22}];
                testGasSpec={app.DrivenGasTable.Data{6}, app.DrivenGasTable.Data{11}, app.DrivenGasTable.Data{16}, app.DrivenGasTable.Data{21}};
                if app.DrivenGasTable.Data{22}>=app.DrivenGasTable.Data{17} && app.DrivenGasTable.Data{17}>=app.DrivenGasTable.Data{12} && app.DrivenGasTable.Data{12}>=app.DrivenGasTable.Data{7}
                    app.DrivenGasTable.Data{27}='NA';
                else
                    app.DrivenGasTable.Data{27}='Error!';
                end
                [XtestGas, XtestGas_bias, MW] = manometry(Pmix',testGasSpec'); %Partial Pressure Bias Calclation
                app.DrivenGasTable.Data{8}=XtestGas(1); %X component 1
                app.DrivenGasTable.Data{13}=XtestGas(2);%X component 2
                app.DrivenGasTable.Data{18}=XtestGas(3);%X component 3
                app.DrivenGasTable.Data{23}=XtestGas(4);%X component 4
                DrivenGasUnityCheck=sum(XtestGas(:));
                app.DrivenGasTable.Data{9}=XtestGas_bias(1); %X Bias component 1
                app.DrivenGasTable.Data{14}=XtestGas_bias(2);%X Bias component 2
                app.DrivenGasTable.Data{19}=XtestGas_bias(3);%X Bias component 3
                app.DrivenGasTable.Data{24}=XtestGas_bias(4);%X Bias component 4
                app.DrivenGasTable.Data{10}=MW(1); %MW Bias component 1
                app.DrivenGasTable.Data{15}=MW(2);%MW Bias component 2
                app.DrivenGasTable.Data{20}=MW(3);%MW Bias component 3
                app.DrivenGasTable.Data{25}=MW(4);%MW Bias component 4
                if DrivenGasUnityCheck==1
                    app.DrivenGasTable.Data{28}=DrivenGasUnityCheck;
                    assignin('base','XtestGas',XtestGas)
                    assignin('base','XtestGas_bias',XtestGas)
                    assignin('base','testGasSpec',testGasSpec)
                else
                    app.DrivenGasTable.Data{28}='Error!';
                end
            else% If Mole Fraction is changed, revert the edit
                app.DrivenGasTable.Data{event.Indices(1), event.Indices(2)} = event.PreviousData;
            end
        end

        % Button pushed function: PlotCh0Button
        function PlotCh0ButtonPushed(app, event)
            EndwalltoTestSection=str2double(app.inEditField_7.Value)*0.0254;% Distance from Test Section centerline to endwall (Specified. Ref.2 CST-1006, CST-1007)
            PxdPsiToVolts=20; %psi/vo
            comments=' ';%Initializes comments string, which builds throughout the code.
            %UserInput = {app.dateTextArea.Value,app.RunTextArea.Value,'C2H4 O2 N2','32.72 131 500', 'He','y','y','y','y','y','2',evalin('base','solnmethod'),app.NASAFileTextArea.Value,evalin('base','ThermoState')}';
            %Test Gas Mole Fractions and Uncertainties from Driven Gas Table callback
            MW=cell2mat({app.DrivenGasTable.Data{10}, app.DrivenGasTable.Data{15}, app.DrivenGasTable.Data{20}, app.DrivenGasTable.Data{25}}); %converts array cells containing doubles to just a matrix of doubles
            MW_mix = sum(evalin('base','XtestGas').*MW);

            timefromendwall=EndwalltoTestSection/evalin('base','Urs'); %[s]
            GasDynamicsFrequency=1/((evalin('base','time_to_EW')/1000)+timefromendwall);
            [BifurcationHeight]=PredictBifurcation(evalin('base','Mout'), evalin('base','gammaOut'), MW_mix); %Predict Bifurcation & predict height

            DiagnosticsUsed=[0 0 0 0 0 0 0 0];% Change these values to 1 as dianostics are used in if statements below.  If the value remains zero, populate diagnostic-specific parameters with 'NA'.
            switch app.Ch0_diagnostic.Value
                case 'Kistler PXD'
                    KistlerSpecs=[str2double(app.Ch0_sensor_freq.Value), 0.00508, 20, EndwalltoTestSection]; %[Hz, m, psi/V, m], [Sample Freq, diam, conversion, location]
                    [Kistler_Voltage, pressureLP, t_ignPIndex, IDT_P, IDT_P_bias, StartIncidentShockSearch, StopReflectedShockSearch, comments]=MlappPressureTrace(evalin('base','Reacting'),str2double(app.PretriggermsTextArea.Value),evalin('base','Pout'),(evalin('base','time_to_EW')/1000), timefromendwall, evalin('base','darkPressureVolts'),evalin('base','time'),evalin('base','pressureVolts'),KistlerSpecs,evalin('base','Uis'),BifurcationHeight,str2double(app.OScopeFreqHzTextArea.Value),comments);
                    %[Ti]=TiFromPi(evalin('base','time'), KistlerSpecs, evalin('base','Tout'), evalin('base','Pout'), evalin('base','gammaOut'), evalin('base','rhoOut'), UserInput{14}); %Estimate time-resolved temperature from pressure trace
                    DiagnosticsUsed (1)=1;

                    cla(app.UIAxes) %Clears Axes if something was plotted previously
                    app.UIAxes.Position = [501 15 716 527];
                    app.Image.Visible = 'off'; app.Port4Label.Visible = 'off';app.Port5Label.Visible = 'off';app.Port6Label.Visible = 'off';app.Port7Label.Visible = 'off';app.Port8Label.Visible = 'off';app.inEditField.Visible = 'off';app.inEditField_2.Visible = 'off';app.inEditField_3.Visible = 'off';app.inEditField_4.Visible = 'off';app.inEditField_5.Visible = 'off';
                    %plot(app.UIAxes,evalin('base','time'),Kistler_Voltage*PxdPsiToVolts*0.068046,'DisplayName','1)pressure raw')
                    hold(app.UIAxes, 'on')
                    plot(app.UIAxes,evalin('base','time'),pressureLP*PxdPsiToVolts*0.068046,'DisplayName','2)pressure lp [atm]')
                    xlim(app.UIAxes,[0 10])
                    ylim(app.UIAxes,[0 max(Kistler_Voltage*PxdPsiToVolts*0.068046)])
                    legend(app.UIAxes)
                    set(app.UIAxes, 'XDir','normal') %gotta switch it back since Uis graph was reversed.
                    xlabel(app.UIAxes,'time [ms]')
                    ylabel(app.UIAxes,'Pressure [atm]')

                    IDT_P='NA';
                    IDT_P_bias='NA';
                    assignin('base','pressuretrace',Kistler_Voltage*PxdPsiToVolts*0.068046)

                case 'OH SW' %As written, this will produce errors whenever OH SW was taken, but HeNe wasn't. It assumes the HeNe frequency is listed in Ch2. That would correspond to a user error anyway because you can't have OH SW IDT without HeNe data.
                    if app.ReactingButton.Value == true %if this was a reacting test, take data
                        [absorb, absorb_lp, t_SW, t_incident, t_EW, spike_ind, comments]=MlappLasTrace('HeNe','None',evalin('base','Reacting'),str2double(app.PretriggermsTextArea.Value),evalin('base','time'), evalin('base','pressureVolts'), evalin('base','HeNePitch'), evalin('base','HeNeCatch'), evalin('base','darkHeNePitch'),evalin('base','darkHeNeCatch'),PxdPsiToVolts,(evalin('base','time_to_EW')/1000), timefromendwall, str2double(app.Ch2_sensor_freq.Value),str2double(app.OScopeFreqHzTextArea.Value),comments);
                        [emission, emissionLP,t_ignOH_SW, IDT_OH_SW, IDT_OH_SW_bias,comments]=MlappemissionTrace('OH SW',t_SW, str2double(app.PretriggermsTextArea.Value), evalin('base','time'),evalin('base','OH_SW'),str2double(app.Ch1_sensor_freq.Value),str2double(app.OScopeFreqHzTextArea.Value), spike_ind,comments);
                        DiagnosticsUsed(2)=1;

                        cla(app.UIAxes) %Clears Axes if something was plotted previously
                        app.UIAxes.Position = [501 15 716 527];
                        app.Image.Visible = 'off'; app.Port4Label.Visible = 'off';app.Port5Label.Visible = 'off';app.Port6Label.Visible = 'off';app.Port7Label.Visible = 'off';app.Port8Label.Visible = 'off';app.inEditField.Visible = 'off';app.inEditField_2.Visible = 'off';app.inEditField_3.Visible = 'off';app.inEditField_4.Visible = 'off';app.inEditField_5.Visible = 'off';
                        hold(app.UIAxes, 'on')
                        plot(app.UIAxes,evalin('base','time'),emission,'DisplayName','OH SW')
                        plot(app.UIAxes,evalin('base','time'),emissionLP,'DisplayName','OH SW LP')
                        xlim(app.UIAxes,[1.5 5])
                        ylim(app.UIAxes,[0 1.5*max(emission)])
                        set(app.UIAxes, 'XDir','normal') %gotta switch it back since Uis graph was reversed.
                        xlabel(app.UIAxes,'time [ms]')
                        ylabel(app.UIAxes,'Volts [V]')
                        legend(app.UIAxes)

                    end %otherwise, do nothing
                case 'HeNe Pitch'
                    %Do Nothing. You'll do the LAS calcs next.
                    cla(app.UIAxes) %Clears Axes if something was plotted previously
                case 'HeNe Catch'
                    [absorbHeNe, absorb_lpHeNe, t_SW, t_incident, t_EW, spike_ind, comments]=MlappLasTrace('HeNe','None',evalin('base','Reacting'),str2double(app.PretriggermsTextArea.Value),evalin('base','time'), evalin('base','pressureVolts'), evalin('base','HeNePitch'), evalin('base','HeNeCatch'), evalin('base','darkHeNePitch'),evalin('base','darkHeNeCatch'),PxdPsiToVolts,(evalin('base','time_to_EW')/1000), timefromendwall, str2double(app.Ch2_sensor_freq.Value),str2double(app.OScopeFreqHzTextArea.Value),comments);
                    DiagnosticsUsed(4)=1;

                    cla(app.UIAxes) %Clears Axes if something was plotted previously
                    app.UIAxes.Position = [501 15 716 527];
                    app.Image.Visible = 'off'; app.Port4Label.Visible = 'off';app.Port5Label.Visible = 'off';app.Port6Label.Visible = 'off';app.Port7Label.Visible = 'off';app.Port8Label.Visible = 'off';app.inEditField.Visible = 'off';app.inEditField_2.Visible = 'off';app.inEditField_3.Visible = 'off';app.inEditField_4.Visible = 'off';app.inEditField_5.Visible = 'off';
                    hold(app.UIAxes, 'on')
                    plot(app.UIAxes,evalin('base','time'),absorbHeNe,'DisplayName','1)absorb HeNe raw')
                    plot(app.UIAxes,evalin('base','time'),absorb_lpHeNe,'DisplayName','2)absorb HeNe  lp')
                    xlim(app.UIAxes,[0 10])
                    ylim(app.UIAxes,[0 max(absorbHeNe)])
                    legend(app.UIAxes)
                    set(app.UIAxes, 'XDir','normal') %gotta switch it back since Uis graph was reversed.
                    xlabel(app.UIAxes,'time [ms]')
                    ylabel(app.UIAxes,'Absorbance [-ln(It/I0]')

                case 'OH EW'%As written, this will produce errors whenever OH SW was taken, but HeNe wasn't. It assumes the HeNe frequency is listed in Ch2. That would correspond to a user error anyway because you can't have OH SW IDT without HeNe data.
                    if app.ReactingButton.Value == true %if this was a reacting test, take data
                        [absorb, absorb_lp, t_SW, t_incident, t_EW, spike_ind, comments]=MlappLasTrace('HeNe','None',evalin('base','Reacting'),str2double(app.PretriggermsTextArea.Value),evalin('base','time'), evalin('base','pressureVolts'), evalin('base','HeNePitch'), evalin('base','HeNeCatch'), evalin('base','darkHeNePitch'),evalin('base','darkHeNeCatch'),PxdPsiToVolts,(evalin('base','time_to_EW')/1000), timefromendwall, str2double(app.Ch2_sensor_freq.Value),str2double(app.OScopeFreqHzTextArea.Value),comments);
                        [emission, emissionLP,t_ignOH_EW, IDT_OH_EW, IDT_OH_EW_bias,comments]=MlappemissionTrace('OH EW',t_EW, str2double(app.PretriggermsTextArea.Value), evalin('base','time'),evalin('base','OH_EW'),str2double(app.Ch1_sensor_freq.Value),str2double(app.OScopeFreqHzTextArea.Value), spike_ind,comments);
                        DiagnosticsUsed(5)=1;

                        cla(app.UIAxes) %Clears Axes if something was plotted previously
                        app.UIAxes.Position = [501 15 716 527];
                        app.Image.Visible = 'off'; app.Port4Label.Visible = 'off';app.Port5Label.Visible = 'off';app.Port6Label.Visible = 'off';app.Port7Label.Visible = 'off';app.Port8Label.Visible = 'off';app.inEditField.Visible = 'off';app.inEditField_2.Visible = 'off';app.inEditField_3.Visible = 'off';app.inEditField_4.Visible = 'off';app.inEditField_5.Visible = 'off';
                        hold(app.UIAxes, 'on')
                        plot(app.UIAxes,evalin('base','time'),emission,'DisplayName','OH EW')
                        plot(app.UIAxes,evalin('base','time'),emissionLP,'DisplayName','OH EW LP')
                        xlim(app.UIAxes,[1.5 5])
                        ylim(app.UIAxes,[min(emission) max(emissionLP)])
                        set(app.UIAxes, 'XDir','normal') %gotta switch it back since Uis graph was reversed.
                        xlabel(app.UIAxes,'time [ms]')
                        ylabel(app.UIAxes,'Volts [V]')
                        legend(app.UIAxes)

                    end %otherwise, do nothing
                case 'PCB 5'
                    %Do nothing.  Michael had some code for this, but I don't remember what for.
                    cla(app.UIAxes) %Clears Axes if something was plotted previously
                case 'CO2 Pitch'
                    %Do nothing.  You'll do the LAS calcs next.
                    cla(app.UIAxes) %Clears Axes if something was plotted previously
                case 'CO2 Catch' %Should really add a more transparent way to show that the filter frequency does not equal the sensor frequency shown in the GUI
                    [absorbCO2, absorb_lpCO2, t_SW, t_incident, t_EW, spike_ind, comments]=MlappLasTrace('CO2','Pinkowski_C2H4',evalin('base','Reacting'),str2double(app.PretriggermsTextArea.Value),evalin('base','time'), evalin('base','pressureVolts'), evalin('base','CO2Pitch'), evalin('base','CO2Catch'), evalin('base','darkCO2Pitch'),evalin('base','darkCO2Catch'),PxdPsiToVolts,(evalin('base','time_to_EW')/1000), timefromendwall,GasDynamicsFrequency, str2double(app.OScopeFreqHzTextArea.Value),comments);
                    DiagnosticsUsed(8)=1;

                    cla(app.UIAxes) %Clears Axes if something was plotted previously
                    app.UIAxes.Position = [501 15 716 527];
                    app.Image.Visible = 'off'; app.Port4Label.Visible = 'off';app.Port5Label.Visible = 'off';app.Port6Label.Visible = 'off';app.Port7Label.Visible = 'off';app.Port8Label.Visible = 'off';app.inEditField.Visible = 'off';app.inEditField_2.Visible = 'off';app.inEditField_3.Visible = 'off';app.inEditField_4.Visible = 'off';app.inEditField_5.Visible = 'off';
                    hold(app.UIAxes, 'on')
                    plot(app.UIAxes,evalin('base','time'),absorbCO2,'DisplayName','1)absorb CO2 raw')
                    plot(app.UIAxes,evalin('base','time'),absorb_lpCO2,'DisplayName','2)absorb CO2  lp')
                    xlim(app.UIAxes,[0 10])
                    ylim(app.UIAxes,[0 max(absorbCO2)])
                    legend(app.UIAxes)
                    set(app.UIAxes, 'XDir','normal') %gotta switch it back since Uis graph was reversed.
                    xlabel(app.UIAxes,'time [ms]')
                    ylabel(app.UIAxes,'Absorbance [-ln(It/I0]')
                otherwise
                    %Do nothing
            end
            %{
            if DiagnosticsUsed(2)==0
                IDT_OH_SW='NA';t_ignOH_SW='NA';IDT_OH_SW_bias='NA';
            end
            if DiagnosticsUsed(4)==0
                HeNeParameters=['NA','NA','NA','NA','NA','NA','NA','NA', 'NA','NA','NA','NA', 'NA', 'NA', 'NA', 'NA'];
                t_SW = 'NA figure out t_A_PXD';
                t_incident = 'NA figure out t_A_PXD';%[ms] time of INCIDENT shock arrival at ENDWALL?
                t_EW ='NA figure out t_A_PXD';
            end
            if DiagnosticsUsed(5)==0
                IDT_OH_EW='NA';t_ignOH_EW='NA';IDT_OH_EW_bias='NA';
            end
            if DiagnosticsUsed(8)==0
                CO2AbsParameters=['NA','NA','NA','NA','NA','NA','NA','NA', 'NA','NA','NA','NA', 'NA', 'NA', 'NA', 'NA'];
            end

            
            %Pause for data analysis and comments, then write Single-point data outputs.
            disp('NO DATA WRITTEN YET! Script is paused so you can look at graphs.  Analyze the graphs, then press ENTER and add any comments.')
            pause
            comments = inputdlg({'Any comments to add?  Dont use commas.'},'User Comments',[5 50],{comments});
            SinglePointData=[UserInput{1} strcat(UserInput{2},'%03d') testGasSpec XtestGas phi T1 P1 Uis Uis_bias Urs ... %Initital Conditions and Frosh Input
                Pout(1) P2_bias Tout(1) T2_bias gammaOut(2) Pout(2) P5_bias Tout(2) T5_bias gammaOut(3) 1000/Tout(2) ... %Frosh Output.  States 2 and 5
                IDT_OH_EW IDT_OH_EW_bias t_EW t_ignOH_EW IDT_OH_SW IDT_OH_SW_bias t_SW t_ignOH_SW IDT_P IDT_P_bias ...%Ignition Delay Time from all 3 sources
                CO2AbsParameters,HeNeParameters comments]; %any user comments to alert the user to bad data or explanations of unusual data.
            writecell(SinglePointData,'CST_ProcessorOutput.txt','Delimiter',',','WriteMode','Append')
            disp('Data written. All done.')
            %}
        end

        % Button pushed function: PlotCh1Button
        function PlotCh1ButtonPushed(app, event)
            EndwalltoTestSection=str2double(app.inEditField_7.Value)*0.0254;% Distance from Test Section centerline to endwall (Specified. Ref.2 CST-1006, CST-1007)
            PxdPsiToVolts=20; %psi/vo
            comments=' ';%Initializes comments string, which builds throughout the code.
            %UserInput = {app.dateTextArea.Value,app.RunTextArea.Value,'C2H4 O2 N2','32.72 131 500', 'He','y','y','y','y','y','2',evalin('base','solnmethod'),app.NASAFileTextArea.Value,evalin('base','ThermoState')}';
            %Test Gas Mole Fractions and Uncertainties from Driven Gas Table callback
            MW=cell2mat({app.DrivenGasTable.Data{10}, app.DrivenGasTable.Data{15}, app.DrivenGasTable.Data{20}, app.DrivenGasTable.Data{25}}); %converts array cells containing doubles to just a matrix of doubles
            MW_mix = sum(evalin('base','XtestGas').*MW);

            timefromendwall=EndwalltoTestSection/evalin('base','Urs'); %[s]
            GasDynamicsFrequency=1/((evalin('base','time_to_EW')/1000)+timefromendwall);
            [BifurcationHeight]=PredictBifurcation(evalin('base','Mout'), evalin('base','gammaOut'), MW_mix); %Predict Bifurcation & predict height

            DiagnosticsUsed=[0 0 0 0 0 0 0 0];% Change these values to 1 as dianostics are used in if statements below.  If the value remains zero, populate diagnostic-specific parameters with 'NA'.
            switch app.Ch1_diagnostic.Value
                case 'Kistler PXD'
                    KistlerSpecs=[str2double(app.Ch0_sensor_freq.Value), 0.00508, 20, EndwalltoTestSection]; %[Hz, m, psi/V, m], [Sample Freq, diam, conversion, location]
                    [Kistler_Voltage, pressureLP, t_ignPIndex, IDT_P, IDT_P_bias, StartIncidentShockSearch, StopReflectedShockSearch, comments]=MlappPressureTrace(evalin('base','Reacting'),str2double(app.PretriggermsTextArea.Value),evalin('base','Pout'),(evalin('base','time_to_EW')/1000), timefromendwall, evalin('base','darkPressureVolts'),evalin('base','time'),evalin('base','pressureVolts'),KistlerSpecs,evalin('base','Uis'),BifurcationHeight,str2double(app.OScopeFreqHzTextArea.Value),comments);
                    %[Ti]=TiFromPi(evalin('base','time'), KistlerSpecs, evalin('base','Tout'), evalin('base','Pout'), evalin('base','gammaOut'), evalin('base','rhoOut'), UserInput{14}); %Estimate time-resolved temperature from pressure trace
                    DiagnosticsUsed (1)=1;

                    cla(app.UIAxes) %Clears Axes if something was plotted previously
                    app.UIAxes.Position = [501 15 716 527];
                    app.Image.Visible = 'off'; app.Port4Label.Visible = 'off';app.Port5Label.Visible = 'off';app.Port6Label.Visible = 'off';app.Port7Label.Visible = 'off';app.Port8Label.Visible = 'off';app.inEditField.Visible = 'off';app.inEditField_2.Visible = 'off';app.inEditField_3.Visible = 'off';app.inEditField_4.Visible = 'off';app.inEditField_5.Visible = 'off';
                    plot(app.UIAxes,evalin('base','time'),Kistler_Voltage*PxdPsiToVolts*0.068046,'DisplayName','1)pressure raw')
                    hold(app.UIAxes, 'on')
                    plot(app.UIAxes,evalin('base','time'),pressureLP*PxdPsiToVolts*0.068046,'DisplayName','2)pressure lp')
                    xlim(app.UIAxes,[0 10])
                    ylim(app.UIAxes,[0 max(Kistler_Voltage*PxdPsiToVolts*0.068046)])
                    legend(app.UIAxes)
                    set(app.UIAxes, 'XDir','normal') %gotta switch it back since Uis graph was reversed.
                    xlabel(app.UIAxes,'time [ms]')
                    ylabel(app.UIAxes,'Pressure [atm]')

                    IDT_P='NA';
                    IDT_P_bias='NA';

                case 'OH SW' %As written, this will produce errors whenever OH SW was taken, but HeNe wasn't. It assumes the HeNe frequency is listed in Ch2. That would correspond to a user error anyway because you can't have OH SW IDT without HeNe data.
                    if app.ReactingButton.Value == true %if this was a reacting test, take data
                        [absorb, absorb_lp, t_SW, t_incident, t_EW, spike_ind, comments]=MlappLasTrace('HeNe','None',evalin('base','Reacting'),str2double(app.PretriggermsTextArea.Value),evalin('base','time'), evalin('base','pressureVolts'), evalin('base','HeNePitch'), evalin('base','HeNeCatch'), evalin('base','darkHeNePitch'),evalin('base','darkHeNeCatch'),PxdPsiToVolts,(evalin('base','time_to_EW')/1000), timefromendwall, str2double(app.Ch2_sensor_freq.Value),str2double(app.OScopeFreqHzTextArea.Value),comments);
                        [emission, emissionLP,t_ignOH_SW, IDT_OH_SW, IDT_OH_SW_bias,comments]=MlappemissionTrace('OH SW',t_SW, str2double(app.PretriggermsTextArea.Value), evalin('base','time'),evalin('base','OH_SW'),str2double(app.Ch1_sensor_freq.Value),str2double(app.OScopeFreqHzTextArea.Value), spike_ind,comments);
                        DiagnosticsUsed(2)=1;

                        cla(app.UIAxes) %Clears Axes if something was plotted previously
                        app.UIAxes.Position = [501 15 716 527];
                        app.Image.Visible = 'off'; app.Port4Label.Visible = 'off';app.Port5Label.Visible = 'off';app.Port6Label.Visible = 'off';app.Port7Label.Visible = 'off';app.Port8Label.Visible = 'off';app.inEditField.Visible = 'off';app.inEditField_2.Visible = 'off';app.inEditField_3.Visible = 'off';app.inEditField_4.Visible = 'off';app.inEditField_5.Visible = 'off';
                        hold(app.UIAxes, 'on')
                        plot(app.UIAxes,evalin('base','time'),emission,'DisplayName','OH SW')
                        plot(app.UIAxes,evalin('base','time'),emissionLP,'DisplayName','OH SW LP')
                        xlim(app.UIAxes,[1.5 5])
                        ylim(app.UIAxes,[0 1.5*max(emission)])
                        set(app.UIAxes, 'XDir','normal') %gotta switch it back since Uis graph was reversed.
                        xlabel(app.UIAxes,'time [ms]')
                        ylabel(app.UIAxes,'Volts [V]')
                        legend(app.UIAxes)

                    end %otherwise, do nothing
                case 'HeNe Pitch'
                    %Do Nothing. You'll do the LAS calcs next.
                    cla(app.UIAxes) %Clears Axes if something was plotted previously
                case 'HeNe Catch'
                    [absorbHeNe, absorb_lpHeNe, t_SW, t_incident, t_EW, spike_ind, comments]=MlappLasTrace('HeNe','None',evalin('base','Reacting'),str2double(app.PretriggermsTextArea.Value),evalin('base','time'), evalin('base','pressureVolts'), evalin('base','HeNePitch'), evalin('base','HeNeCatch'), evalin('base','darkHeNePitch'),evalin('base','darkHeNeCatch'),PxdPsiToVolts,(evalin('base','time_to_EW')/1000), timefromendwall, str2double(app.Ch2_sensor_freq.Value),str2double(app.OScopeFreqHzTextArea.Value),comments);
                    DiagnosticsUsed(4)=1;

                    cla(app.UIAxes) %Clears Axes if something was plotted previously
                    app.UIAxes.Position = [501 15 716 527];
                    app.Image.Visible = 'off'; app.Port4Label.Visible = 'off';app.Port5Label.Visible = 'off';app.Port6Label.Visible = 'off';app.Port7Label.Visible = 'off';app.Port8Label.Visible = 'off';app.inEditField.Visible = 'off';app.inEditField_2.Visible = 'off';app.inEditField_3.Visible = 'off';app.inEditField_4.Visible = 'off';app.inEditField_5.Visible = 'off';
                    hold(app.UIAxes, 'on')
                    plot(app.UIAxes,evalin('base','time'),absorbHeNe,'DisplayName','1)absorb HeNe raw')
                    plot(app.UIAxes,evalin('base','time'),absorb_lpHeNe,'DisplayName','2)absorb HeNe  lp')
                    xlim(app.UIAxes,[0 10])
                    ylim(app.UIAxes,[0 max(absorbHeNe)])
                    legend(app.UIAxes)
                    set(app.UIAxes, 'XDir','normal') %gotta switch it back since Uis graph was reversed.
                    xlabel(app.UIAxes,'time [ms]')
                    ylabel(app.UIAxes,'Absorbance [-ln(It/I0]')

                case 'OH EW'%As written, this will produce errors whenever OH SW was taken, but HeNe wasn't. It assumes the HeNe frequency is listed in Ch2. That would correspond to a user error anyway because you can't have OH SW IDT without HeNe data.
                    if app.ReactingButton.Value == true %if this was a reacting test, take data
                        [absorb, absorb_lp, t_SW, t_incident, t_EW, spike_ind, comments]=MlappLasTrace('HeNe','None',evalin('base','Reacting'),str2double(app.PretriggermsTextArea.Value),evalin('base','time'), evalin('base','pressureVolts'), evalin('base','HeNePitch'), evalin('base','HeNeCatch'), evalin('base','darkHeNePitch'),evalin('base','darkHeNeCatch'),PxdPsiToVolts,(evalin('base','time_to_EW')/1000), timefromendwall, str2double(app.Ch2_sensor_freq.Value),str2double(app.OScopeFreqHzTextArea.Value),comments);
                        [emission, emissionLP,t_ignOH_EW, IDT_OH_EW, IDT_OH_EW_bias,comments]=MlappemissionTrace('OH EW',t_EW, str2double(app.PretriggermsTextArea.Value), evalin('base','time'),evalin('base','OH_EW'),str2double(app.Ch1_sensor_freq.Value),str2double(app.OScopeFreqHzTextArea.Value), spike_ind,comments);
                        DiagnosticsUsed(5)=1;

                        cla(app.UIAxes) %Clears Axes if something was plotted previously
                        app.UIAxes.Position = [501 15 716 527];
                        app.Image.Visible = 'off'; app.Port4Label.Visible = 'off';app.Port5Label.Visible = 'off';app.Port6Label.Visible = 'off';app.Port7Label.Visible = 'off';app.Port8Label.Visible = 'off';app.inEditField.Visible = 'off';app.inEditField_2.Visible = 'off';app.inEditField_3.Visible = 'off';app.inEditField_4.Visible = 'off';app.inEditField_5.Visible = 'off';
                        hold(app.UIAxes, 'on')
                        plot(app.UIAxes,evalin('base','time'),emission,'DisplayName','OH EW')
                        plot(app.UIAxes,evalin('base','time'),emissionLP,'DisplayName','OH EW LP')
                        xlim(app.UIAxes,[1.5 5])
                        ylim(app.UIAxes,[min(emission) max(emissionLP)])
                        set(app.UIAxes, 'XDir','normal') %gotta switch it back since Uis graph was reversed.
                        xlabel(app.UIAxes,'time [ms]')
                        ylabel(app.UIAxes,'Volts [V]')
                        legend(app.UIAxes)

                    end %otherwise, do nothing
                case 'PCB 5'
                    %Do nothing.  Michael had some code for this, but I don't remember what for.
                    cla(app.UIAxes) %Clears Axes if something was plotted previously
                case 'CO2 Pitch'
                    %Do nothing.  You'll do the LAS calcs next.
                    cla(app.UIAxes) %Clears Axes if something was plotted previously
                case 'CO2 Catch' %Should really add a more transparent way to show that the filter frequency does not equal the sensor frequency shown in the GUI
                    [absorbCO2, absorb_lpCO2, t_SW, t_incident, t_EW, spike_ind, comments]=MlappLasTrace('CO2','Pinkowski_C2H4',evalin('base','Reacting'),str2double(app.PretriggermsTextArea.Value),evalin('base','time'), evalin('base','pressureVolts'), evalin('base','CO2Pitch'), evalin('base','CO2Catch'), evalin('base','darkCO2Pitch'),evalin('base','darkCO2Catch'),PxdPsiToVolts,(evalin('base','time_to_EW')/1000), timefromendwall,GasDynamicsFrequency, str2double(app.OScopeFreqHzTextArea.Value),comments);
                    DiagnosticsUsed(8)=1;

                    cla(app.UIAxes) %Clears Axes if something was plotted previously
                    app.UIAxes.Position = [501 15 716 527];
                    app.Image.Visible = 'off'; app.Port4Label.Visible = 'off';app.Port5Label.Visible = 'off';app.Port6Label.Visible = 'off';app.Port7Label.Visible = 'off';app.Port8Label.Visible = 'off';app.inEditField.Visible = 'off';app.inEditField_2.Visible = 'off';app.inEditField_3.Visible = 'off';app.inEditField_4.Visible = 'off';app.inEditField_5.Visible = 'off';
                    hold(app.UIAxes, 'on')
                    plot(app.UIAxes,evalin('base','time'),absorbCO2,'DisplayName','1)absorb CO2 raw')
                    plot(app.UIAxes,evalin('base','time'),absorb_lpCO2,'DisplayName','2)absorb CO2  lp')
                    xlim(app.UIAxes,[0 10])
                    ylim(app.UIAxes,[0 max(absorbCO2)])
                    legend(app.UIAxes)
                    set(app.UIAxes, 'XDir','normal') %gotta switch it back since Uis graph was reversed.
                    xlabel(app.UIAxes,'time [ms]')
                    ylabel(app.UIAxes,'Absorbance [-ln(It/I0]')
                otherwise
                    %Do nothing
            end
        end

        % Button pushed function: PlotCh2Button
        function PlotCh2ButtonPushed(app, event)
            EndwalltoTestSection=str2double(app.inEditField_7.Value)*0.0254;% Distance from Test Section centerline to endwall (Specified. Ref.2 CST-1006, CST-1007)
            PxdPsiToVolts=20; %psi/vo
            comments=' ';%Initializes comments string, which builds throughout the code.
            %UserInput = {app.dateTextArea.Value,app.RunTextArea.Value,'C2H4 O2 N2','32.72 131 500', 'He','y','y','y','y','y','2',evalin('base','solnmethod'),app.NASAFileTextArea.Value,evalin('base','ThermoState')}';
            %Test Gas Mole Fractions and Uncertainties from Driven Gas Table callback
            MW=cell2mat({app.DrivenGasTable.Data{10}, app.DrivenGasTable.Data{15}, app.DrivenGasTable.Data{20}, app.DrivenGasTable.Data{25}}); %converts array cells containing doubles to just a matrix of doubles
            MW_mix = sum(evalin('base','XtestGas').*MW);

            timefromendwall=EndwalltoTestSection/evalin('base','Urs'); %[s]
            GasDynamicsFrequency=1/((evalin('base','time_to_EW')/1000)+timefromendwall);
            [BifurcationHeight]=PredictBifurcation(evalin('base','Mout'), evalin('base','gammaOut'), MW_mix); %Predict Bifurcation & predict height

            DiagnosticsUsed=[0 0 0 0 0 0 0 0];% Change these values to 1 as dianostics are used in if statements below.  If the value remains zero, populate diagnostic-specific parameters with 'NA'.
            switch app.Ch2_diagnostic.Value
                case 'Kistler PXD'
                    KistlerSpecs=[str2double(app.Ch0_sensor_freq.Value), 0.00508, 20, EndwalltoTestSection]; %[Hz, m, psi/V, m], [Sample Freq, diam, conversion, location]
                    [Kistler_Voltage, pressureLP, t_ignPIndex, IDT_P, IDT_P_bias, StartIncidentShockSearch, StopReflectedShockSearch, comments]=MlappPressureTrace(evalin('base','Reacting'),str2double(app.PretriggermsTextArea.Value),evalin('base','Pout'),(evalin('base','time_to_EW')/1000), timefromendwall, evalin('base','darkPressureVolts'),evalin('base','time'),evalin('base','pressureVolts'),KistlerSpecs,evalin('base','Uis'),BifurcationHeight,str2double(app.OScopeFreqHzTextArea.Value),comments);
                    %[Ti]=TiFromPi(evalin('base','time'), KistlerSpecs, evalin('base','Tout'), evalin('base','Pout'), evalin('base','gammaOut'), evalin('base','rhoOut'), UserInput{14}); %Estimate time-resolved temperature from pressure trace
                    DiagnosticsUsed (1)=1;

                    cla(app.UIAxes) %Clears Axes if something was plotted previously
                    app.UIAxes.Position = [501 15 716 527];
                    app.Image.Visible = 'off'; app.Port4Label.Visible = 'off';app.Port5Label.Visible = 'off';app.Port6Label.Visible = 'off';app.Port7Label.Visible = 'off';app.Port8Label.Visible = 'off';app.inEditField.Visible = 'off';app.inEditField_2.Visible = 'off';app.inEditField_3.Visible = 'off';app.inEditField_4.Visible = 'off';app.inEditField_5.Visible = 'off';
                    plot(app.UIAxes,evalin('base','time'),Kistler_Voltage*PxdPsiToVolts*0.068046,'DisplayName','1)pressure raw')
                    hold(app.UIAxes, 'on')
                    plot(app.UIAxes,evalin('base','time'),pressureLP*PxdPsiToVolts*0.068046,'DisplayName','2)pressure lp')
                    xlim(app.UIAxes,[0 10])
                    ylim(app.UIAxes,[0 max(Kistler_Voltage*PxdPsiToVolts*0.068046)])
                    legend(app.UIAxes)
                    set(app.UIAxes, 'XDir','normal') %gotta switch it back since Uis graph was reversed.
                    xlabel(app.UIAxes,'time [ms]')
                    ylabel(app.UIAxes,'Pressure [atm]')

                    IDT_P='NA';
                    IDT_P_bias='NA';

                case 'OH SW' %As written, this will produce errors whenever OH SW was taken, but HeNe wasn't. It assumes the HeNe frequency is listed in Ch2. That would correspond to a user error anyway because you can't have OH SW IDT without HeNe data.
                    if app.ReactingButton.Value == true %if this was a reacting test, take data
                        [absorb, absorb_lp, t_SW, t_incident, t_EW, spike_ind, comments]=MlappLasTrace('HeNe','None',evalin('base','Reacting'),str2double(app.PretriggermsTextArea.Value),evalin('base','time'), evalin('base','pressureVolts'), evalin('base','HeNePitch'), evalin('base','HeNeCatch'), evalin('base','darkHeNePitch'),evalin('base','darkHeNeCatch'),PxdPsiToVolts,(evalin('base','time_to_EW')/1000), timefromendwall, str2double(app.Ch2_sensor_freq.Value),str2double(app.OScopeFreqHzTextArea.Value),comments);
                        [emission, emissionLP,t_ignOH_SW, IDT_OH_SW, IDT_OH_SW_bias,comments]=MlappemissionTrace('OH SW',t_SW, str2double(app.PretriggermsTextArea.Value), evalin('base','time'),evalin('base','OH_SW'),str2double(app.Ch1_sensor_freq.Value),str2double(app.OScopeFreqHzTextArea.Value), spike_ind,comments);
                        DiagnosticsUsed(2)=1;

                        cla(app.UIAxes) %Clears Axes if something was plotted previously
                        app.UIAxes.Position = [501 15 716 527];
                        app.Image.Visible = 'off'; app.Port4Label.Visible = 'off';app.Port5Label.Visible = 'off';app.Port6Label.Visible = 'off';app.Port7Label.Visible = 'off';app.Port8Label.Visible = 'off';app.inEditField.Visible = 'off';app.inEditField_2.Visible = 'off';app.inEditField_3.Visible = 'off';app.inEditField_4.Visible = 'off';app.inEditField_5.Visible = 'off';
                        hold(app.UIAxes, 'on')
                        plot(app.UIAxes,evalin('base','time'),emission,'DisplayName','OH SW')
                        plot(app.UIAxes,evalin('base','time'),emissionLP,'DisplayName','OH SW LP')
                        xlim(app.UIAxes,[1.5 5])
                        ylim(app.UIAxes,[0 1.5*max(emission)])
                        set(app.UIAxes, 'XDir','normal') %gotta switch it back since Uis graph was reversed.
                        xlabel(app.UIAxes,'time [ms]')
                        ylabel(app.UIAxes,'Volts [V]')
                        legend(app.UIAxes)

                    end %otherwise, do nothing
                case 'HeNe Pitch'
                    %Do Nothing. You'll do the LAS calcs next.
                    cla(app.UIAxes) %Clears Axes if something was plotted previously
                case 'HeNe Catch'
                    [absorbHeNe, absorb_lpHeNe, t_SW, t_incident, t_EW, spike_ind, comments]=MlappLasTrace('HeNe','None',evalin('base','Reacting'),str2double(app.PretriggermsTextArea.Value),evalin('base','time'), evalin('base','pressureVolts'), evalin('base','HeNePitch'), evalin('base','HeNeCatch'), evalin('base','darkHeNePitch'),evalin('base','darkHeNeCatch'),PxdPsiToVolts,(evalin('base','time_to_EW')/1000), timefromendwall, str2double(app.Ch2_sensor_freq.Value),str2double(app.OScopeFreqHzTextArea.Value),comments);
                    DiagnosticsUsed(4)=1;

                    cla(app.UIAxes) %Clears Axes if something was plotted previously
                    app.UIAxes.Position = [501 15 716 527];
                    app.Image.Visible = 'off'; app.Port4Label.Visible = 'off';app.Port5Label.Visible = 'off';app.Port6Label.Visible = 'off';app.Port7Label.Visible = 'off';app.Port8Label.Visible = 'off';app.inEditField.Visible = 'off';app.inEditField_2.Visible = 'off';app.inEditField_3.Visible = 'off';app.inEditField_4.Visible = 'off';app.inEditField_5.Visible = 'off';
                    hold(app.UIAxes, 'on')
                    plot(app.UIAxes,evalin('base','time'),absorbHeNe,'DisplayName','1)absorb HeNe raw')
                    plot(app.UIAxes,evalin('base','time'),absorb_lpHeNe,'DisplayName','2)absorb HeNe  lp')
                    xlim(app.UIAxes,[0 10])
                    ylim(app.UIAxes,[0 max(absorbHeNe)])
                    legend(app.UIAxes)
                    set(app.UIAxes, 'XDir','normal') %gotta switch it back since Uis graph was reversed.
                    xlabel(app.UIAxes,'time [ms]')
                    ylabel(app.UIAxes,'Absorbance [-ln(It/I0]')

                case 'OH EW'%As written, this will produce errors whenever OH SW was taken, but HeNe wasn't. It assumes the HeNe frequency is listed in Ch2. That would correspond to a user error anyway because you can't have OH SW IDT without HeNe data.
                    if app.ReactingButton.Value == true %if this was a reacting test, take data
                        [absorb, absorb_lp, t_SW, t_incident, t_EW, spike_ind, comments]=MlappLasTrace('HeNe','None',evalin('base','Reacting'),str2double(app.PretriggermsTextArea.Value),evalin('base','time'), evalin('base','pressureVolts'), evalin('base','HeNePitch'), evalin('base','HeNeCatch'), evalin('base','darkHeNePitch'),evalin('base','darkHeNeCatch'),PxdPsiToVolts,(evalin('base','time_to_EW')/1000), timefromendwall, str2double(app.Ch2_sensor_freq.Value),str2double(app.OScopeFreqHzTextArea.Value),comments);
                        [emission, emissionLP,t_ignOH_EW, IDT_OH_EW, IDT_OH_EW_bias,comments]=MlappemissionTrace('OH EW',t_EW, str2double(app.PretriggermsTextArea.Value), evalin('base','time'),evalin('base','OH_EW'),str2double(app.Ch1_sensor_freq.Value),str2double(app.OScopeFreqHzTextArea.Value), spike_ind,comments);
                        DiagnosticsUsed(5)=1;

                        cla(app.UIAxes) %Clears Axes if something was plotted previously
                        app.UIAxes.Position = [501 15 716 527];
                        app.Image.Visible = 'off'; app.Port4Label.Visible = 'off';app.Port5Label.Visible = 'off';app.Port6Label.Visible = 'off';app.Port7Label.Visible = 'off';app.Port8Label.Visible = 'off';app.inEditField.Visible = 'off';app.inEditField_2.Visible = 'off';app.inEditField_3.Visible = 'off';app.inEditField_4.Visible = 'off';app.inEditField_5.Visible = 'off';
                        hold(app.UIAxes, 'on')
                        plot(app.UIAxes,evalin('base','time'),emission,'DisplayName','OH EW')
                        plot(app.UIAxes,evalin('base','time'),emissionLP,'DisplayName','OH EW LP')
                        xlim(app.UIAxes,[1.5 5])
                        ylim(app.UIAxes,[min(emission) max(emissionLP)])
                        set(app.UIAxes, 'XDir','normal') %gotta switch it back since Uis graph was reversed.
                        xlabel(app.UIAxes,'time [ms]')
                        ylabel(app.UIAxes,'Volts [V]')
                        legend(app.UIAxes)

                    end %otherwise, do nothing
                case 'PCB 5'
                    %Do nothing.  Michael had some code for this, but I don't remember what for.
                    cla(app.UIAxes) %Clears Axes if something was plotted previously
                case 'CO2 Pitch'
                    %Do nothing.  You'll do the LAS calcs next.
                    cla(app.UIAxes) %Clears Axes if something was plotted previously
                case 'CO2 Catch' %Should really add a more transparent way to show that the filter frequency does not equal the sensor frequency shown in the GUI
                    [absorbCO2, absorb_lpCO2, t_SW, t_incident, t_EW, spike_ind, comments]=MlappLasTrace('CO2','Pinkowski_C2H4',evalin('base','Reacting'),str2double(app.PretriggermsTextArea.Value),evalin('base','time'), evalin('base','pressureVolts'), evalin('base','CO2Pitch'), evalin('base','CO2Catch'), evalin('base','darkCO2Pitch'),evalin('base','darkCO2Catch'),PxdPsiToVolts,(evalin('base','time_to_EW')/1000), timefromendwall,GasDynamicsFrequency, str2double(app.OScopeFreqHzTextArea.Value),comments);
                    DiagnosticsUsed(8)=1;

                    cla(app.UIAxes) %Clears Axes if something was plotted previously
                    app.UIAxes.Position = [501 15 716 527];
                    app.Image.Visible = 'off'; app.Port4Label.Visible = 'off';app.Port5Label.Visible = 'off';app.Port6Label.Visible = 'off';app.Port7Label.Visible = 'off';app.Port8Label.Visible = 'off';app.inEditField.Visible = 'off';app.inEditField_2.Visible = 'off';app.inEditField_3.Visible = 'off';app.inEditField_4.Visible = 'off';app.inEditField_5.Visible = 'off';
                    hold(app.UIAxes, 'on')
                    plot(app.UIAxes,evalin('base','time'),absorbCO2,'DisplayName','1)absorb CO2 raw')
                    plot(app.UIAxes,evalin('base','time'),absorb_lpCO2,'DisplayName','2)absorb CO2  lp')
                    xlim(app.UIAxes,[0 10])
                    ylim(app.UIAxes,[0 max(absorbCO2)])
                    legend(app.UIAxes)
                    set(app.UIAxes, 'XDir','normal') %gotta switch it back since Uis graph was reversed.
                    xlabel(app.UIAxes,'time [ms]')
                    ylabel(app.UIAxes,'Absorbance [-ln(It/I0]')
                otherwise
                    %Do nothing
            end
        end

        % Button pushed function: PlotCh3Button
        function PlotCh3ButtonPushed(app, event)
            EndwalltoTestSection=str2double(app.inEditField_7.Value)*0.0254;% Distance from Test Section centerline to endwall (Specified. Ref.2 CST-1006, CST-1007)
            PxdPsiToVolts=20; %psi/vo
            comments=' ';%Initializes comments string, which builds throughout the code.
            %UserInput = {app.dateTextArea.Value,app.RunTextArea.Value,'C2H4 O2 N2','32.72 131 500', 'He','y','y','y','y','y','2',evalin('base','solnmethod'),app.NASAFileTextArea.Value,evalin('base','ThermoState')}';
            %Test Gas Mole Fractions and Uncertainties from Driven Gas Table callback
            MW=cell2mat({app.DrivenGasTable.Data{10}, app.DrivenGasTable.Data{15}, app.DrivenGasTable.Data{20}, app.DrivenGasTable.Data{25}}); %converts array cells containing doubles to just a matrix of doubles
            MW_mix = sum(evalin('base','XtestGas').*MW);

            timefromendwall=EndwalltoTestSection/evalin('base','Urs'); %[s]
            GasDynamicsFrequency=1/((evalin('base','time_to_EW')/1000)+timefromendwall);
            [BifurcationHeight]=PredictBifurcation(evalin('base','Mout'), evalin('base','gammaOut'), MW_mix); %Predict Bifurcation & predict height

            DiagnosticsUsed=[0 0 0 0 0 0 0 0];% Change these values to 1 as dianostics are used in if statements below.  If the value remains zero, populate diagnostic-specific parameters with 'NA'.
            switch app.Ch3_diagnostic.Value
                case 'Kistler PXD'
                    KistlerSpecs=[str2double(app.Ch0_sensor_freq.Value), 0.00508, 20, EndwalltoTestSection]; %[Hz, m, psi/V, m], [Sample Freq, diam, conversion, location]
                    [Kistler_Voltage, pressureLP, t_ignPIndex, IDT_P, IDT_P_bias, StartIncidentShockSearch, StopReflectedShockSearch, comments]=MlappPressureTrace(evalin('base','Reacting'),str2double(app.PretriggermsTextArea.Value),evalin('base','Pout'),(evalin('base','time_to_EW')/1000), timefromendwall, evalin('base','darkPressureVolts'),evalin('base','time'),evalin('base','pressureVolts'),KistlerSpecs,evalin('base','Uis'),BifurcationHeight,str2double(app.OScopeFreqHzTextArea.Value),comments);
                    %[Ti]=TiFromPi(evalin('base','time'), KistlerSpecs, evalin('base','Tout'), evalin('base','Pout'), evalin('base','gammaOut'), evalin('base','rhoOut'), UserInput{14}); %Estimate time-resolved temperature from pressure trace
                    DiagnosticsUsed (1)=1;

                    cla(app.UIAxes) %Clears Axes if something was plotted previously
                    app.UIAxes.Position = [501 15 716 527];
                    app.Image.Visible = 'off'; app.Port4Label.Visible = 'off';app.Port5Label.Visible = 'off';app.Port6Label.Visible = 'off';app.Port7Label.Visible = 'off';app.Port8Label.Visible = 'off';app.inEditField.Visible = 'off';app.inEditField_2.Visible = 'off';app.inEditField_3.Visible = 'off';app.inEditField_4.Visible = 'off';app.inEditField_5.Visible = 'off';
                    plot(app.UIAxes,evalin('base','time'),Kistler_Voltage*PxdPsiToVolts*0.068046,'DisplayName','1)pressure raw')
                    hold(app.UIAxes, 'on')
                    plot(app.UIAxes,evalin('base','time'),pressureLP*PxdPsiToVolts*0.068046,'DisplayName','2)pressure lp')
                    xlim(app.UIAxes,[0 10])
                    ylim(app.UIAxes,[0 max(Kistler_Voltage*PxdPsiToVolts*0.068046)])
                    legend(app.UIAxes)
                    set(app.UIAxes, 'XDir','normal') %gotta switch it back since Uis graph was reversed.
                    xlabel(app.UIAxes,'time [ms]')
                    ylabel(app.UIAxes,'Pressure [atm]')

                    IDT_P='NA';
                    IDT_P_bias='NA';

                case 'OH SW' %As written, this will produce errors whenever OH SW was taken, but HeNe wasn't. It assumes the HeNe frequency is listed in Ch2. That would correspond to a user error anyway because you can't have OH SW IDT without HeNe data.
                    if app.ReactingButton.Value == true %if this was a reacting test, take data
                        [absorb, absorb_lp, t_SW, t_incident, t_EW, spike_ind, comments]=MlappLasTrace('HeNe','None',evalin('base','Reacting'),str2double(app.PretriggermsTextArea.Value),evalin('base','time'), evalin('base','pressureVolts'), evalin('base','HeNePitch'), evalin('base','HeNeCatch'), evalin('base','darkHeNePitch'),evalin('base','darkHeNeCatch'),PxdPsiToVolts,(evalin('base','time_to_EW')/1000), timefromendwall, str2double(app.Ch2_sensor_freq.Value),str2double(app.OScopeFreqHzTextArea.Value),comments);
                        [emission, emissionLP,t_ignOH_SW, IDT_OH_SW, IDT_OH_SW_bias,comments]=MlappemissionTrace('OH SW',t_SW, str2double(app.PretriggermsTextArea.Value), evalin('base','time'),evalin('base','OH_SW'),str2double(app.Ch1_sensor_freq.Value),str2double(app.OScopeFreqHzTextArea.Value), spike_ind,comments);
                        DiagnosticsUsed(2)=1;

                        cla(app.UIAxes) %Clears Axes if something was plotted previously
                        app.UIAxes.Position = [501 15 716 527];
                        app.Image.Visible = 'off'; app.Port4Label.Visible = 'off';app.Port5Label.Visible = 'off';app.Port6Label.Visible = 'off';app.Port7Label.Visible = 'off';app.Port8Label.Visible = 'off';app.inEditField.Visible = 'off';app.inEditField_2.Visible = 'off';app.inEditField_3.Visible = 'off';app.inEditField_4.Visible = 'off';app.inEditField_5.Visible = 'off';
                        hold(app.UIAxes, 'on')
                        plot(app.UIAxes,evalin('base','time'),emission,'DisplayName','OH SW')
                        plot(app.UIAxes,evalin('base','time'),emissionLP,'DisplayName','OH SW LP')
                        xlim(app.UIAxes,[1.5 5])
                        ylim(app.UIAxes,[0 1.5*max(emission)])
                        set(app.UIAxes, 'XDir','normal') %gotta switch it back since Uis graph was reversed.
                        xlabel(app.UIAxes,'time [ms]')
                        ylabel(app.UIAxes,'Volts [V]')
                        legend(app.UIAxes)

                    end %otherwise, do nothing
                case 'HeNe Pitch'
                    %Do Nothing. You'll do the LAS calcs next.
                    cla(app.UIAxes) %Clears Axes if something was plotted previously
                case 'HeNe Catch'
                    [absorbHeNe, absorb_lpHeNe, t_SW, t_incident, t_EW, spike_ind, comments]=MlappLasTrace('HeNe','None',evalin('base','Reacting'),str2double(app.PretriggermsTextArea.Value),evalin('base','time'), evalin('base','pressureVolts'), evalin('base','HeNePitch'), evalin('base','HeNeCatch'), evalin('base','darkHeNePitch'),evalin('base','darkHeNeCatch'),PxdPsiToVolts,(evalin('base','time_to_EW')/1000), timefromendwall, str2double(app.Ch2_sensor_freq.Value),str2double(app.OScopeFreqHzTextArea.Value),comments);
                    DiagnosticsUsed(4)=1;

                    cla(app.UIAxes) %Clears Axes if something was plotted previously
                    app.UIAxes.Position = [501 15 716 527];
                    app.Image.Visible = 'off'; app.Port4Label.Visible = 'off';app.Port5Label.Visible = 'off';app.Port6Label.Visible = 'off';app.Port7Label.Visible = 'off';app.Port8Label.Visible = 'off';app.inEditField.Visible = 'off';app.inEditField_2.Visible = 'off';app.inEditField_3.Visible = 'off';app.inEditField_4.Visible = 'off';app.inEditField_5.Visible = 'off';
                    hold(app.UIAxes, 'on')
                    plot(app.UIAxes,evalin('base','time'),absorbHeNe,'DisplayName','1)absorb HeNe raw')
                    plot(app.UIAxes,evalin('base','time'),absorb_lpHeNe,'DisplayName','2)absorb HeNe  lp')
                    xlim(app.UIAxes,[0 10])
                    ylim(app.UIAxes,[0 max(absorbHeNe)])
                    legend(app.UIAxes)
                    set(app.UIAxes, 'XDir','normal') %gotta switch it back since Uis graph was reversed.
                    xlabel(app.UIAxes,'time [ms]')
                    ylabel(app.UIAxes,'Absorbance [-ln(It/I0]')

                case 'OH EW'%As written, this will produce errors whenever OH SW was taken, but HeNe wasn't. It assumes the HeNe frequency is listed in Ch2. That would correspond to a user error anyway because you can't have OH SW IDT without HeNe data.
                    if app.ReactingButton.Value == true %if this was a reacting test, take data
                        [absorb, absorb_lp, t_SW, t_incident, t_EW, spike_ind, comments]=MlappLasTrace('HeNe','None',evalin('base','Reacting'),str2double(app.PretriggermsTextArea.Value),evalin('base','time'), evalin('base','pressureVolts'), evalin('base','HeNePitch'), evalin('base','HeNeCatch'), evalin('base','darkHeNePitch'),evalin('base','darkHeNeCatch'),PxdPsiToVolts,(evalin('base','time_to_EW')/1000), timefromendwall, str2double(app.Ch2_sensor_freq.Value),str2double(app.OScopeFreqHzTextArea.Value),comments);
                        [emission, emissionLP,t_ignOH_EW, IDT_OH_EW, IDT_OH_EW_bias,comments]=MlappemissionTrace('OH EW',t_EW, str2double(app.PretriggermsTextArea.Value), evalin('base','time'),evalin('base','OH_EW'),str2double(app.Ch1_sensor_freq.Value),str2double(app.OScopeFreqHzTextArea.Value), spike_ind,comments);
                        DiagnosticsUsed(5)=1;

                        cla(app.UIAxes) %Clears Axes if something was plotted previously
                        app.UIAxes.Position = [501 15 716 527];
                        app.Image.Visible = 'off'; app.Port4Label.Visible = 'off';app.Port5Label.Visible = 'off';app.Port6Label.Visible = 'off';app.Port7Label.Visible = 'off';app.Port8Label.Visible = 'off';app.inEditField.Visible = 'off';app.inEditField_2.Visible = 'off';app.inEditField_3.Visible = 'off';app.inEditField_4.Visible = 'off';app.inEditField_5.Visible = 'off';
                        hold(app.UIAxes, 'on')
                        plot(app.UIAxes,evalin('base','time'),emission,'DisplayName','OH EW')
                        plot(app.UIAxes,evalin('base','time'),emissionLP,'DisplayName','OH EW LP')
                        xlim(app.UIAxes,[1.5 5])
                        ylim(app.UIAxes,[min(emission) max(emissionLP)])
                        set(app.UIAxes, 'XDir','normal') %gotta switch it back since Uis graph was reversed.
                        xlabel(app.UIAxes,'time [ms]')
                        ylabel(app.UIAxes,'Volts [V]')
                        legend(app.UIAxes)

                    end %otherwise, do nothing
                case 'PCB 5'
                    %Do nothing.  Michael had some code for this, but I don't remember what for.
                    cla(app.UIAxes) %Clears Axes if something was plotted previously
                case 'CO2 Pitch'
                    %Do nothing.  You'll do the LAS calcs next.
                    cla(app.UIAxes) %Clears Axes if something was plotted previously
                case 'CO2 Catch' %Should really add a more transparent way to show that the filter frequency does not equal the sensor frequency shown in the GUI
                    [absorbCO2, absorb_lpCO2, t_SW, t_incident, t_EW, spike_ind, comments]=MlappLasTrace('CO2','Pinkowski_C2H4',evalin('base','Reacting'),str2double(app.PretriggermsTextArea.Value),evalin('base','time'), evalin('base','pressureVolts'), evalin('base','CO2Pitch'), evalin('base','CO2Catch'), evalin('base','darkCO2Pitch'),evalin('base','darkCO2Catch'),PxdPsiToVolts,(evalin('base','time_to_EW')/1000), timefromendwall,GasDynamicsFrequency, str2double(app.OScopeFreqHzTextArea.Value),comments);
                    DiagnosticsUsed(8)=1;

                    cla(app.UIAxes) %Clears Axes if something was plotted previously
                    app.UIAxes.Position = [501 15 716 527];
                    app.Image.Visible = 'off'; app.Port4Label.Visible = 'off';app.Port5Label.Visible = 'off';app.Port6Label.Visible = 'off';app.Port7Label.Visible = 'off';app.Port8Label.Visible = 'off';app.inEditField.Visible = 'off';app.inEditField_2.Visible = 'off';app.inEditField_3.Visible = 'off';app.inEditField_4.Visible = 'off';app.inEditField_5.Visible = 'off';
                    hold(app.UIAxes, 'on')
                    plot(app.UIAxes,evalin('base','time'),absorbCO2,'DisplayName','1)absorb CO2 raw')
                    plot(app.UIAxes,evalin('base','time'),absorb_lpCO2,'DisplayName','2)absorb CO2  lp')
                    xlim(app.UIAxes,[0 10])
                    ylim(app.UIAxes,[0 max(absorbCO2)])
                    legend(app.UIAxes)
                    set(app.UIAxes, 'XDir','normal') %gotta switch it back since Uis graph was reversed.
                    xlabel(app.UIAxes,'time [ms]')
                    ylabel(app.UIAxes,'Absorbance [-ln(It/I0]')
                otherwise
                    %Do nothing
            end
        end

        % Button pushed function: PlotCh4Button
        function PlotCh4ButtonPushed(app, event)
            EndwalltoTestSection=str2double(app.inEditField_7.Value)*0.0254;% Distance from Test Section centerline to endwall (Specified. Ref.2 CST-1006, CST-1007)
            PxdPsiToVolts=20; %psi/vo
            comments=' ';%Initializes comments string, which builds throughout the code.
            %UserInput = {app.dateTextArea.Value,app.RunTextArea.Value,'C2H4 O2 N2','32.72 131 500', 'He','y','y','y','y','y','2',evalin('base','solnmethod'),app.NASAFileTextArea.Value,evalin('base','ThermoState')}';
            %Test Gas Mole Fractions and Uncertainties from Driven Gas Table callback
            MW=cell2mat({app.DrivenGasTable.Data{10}, app.DrivenGasTable.Data{15}, app.DrivenGasTable.Data{20}, app.DrivenGasTable.Data{25}}); %converts array cells containing doubles to just a matrix of doubles
            MW_mix = sum(evalin('base','XtestGas').*MW);

            timefromendwall=EndwalltoTestSection/evalin('base','Urs'); %[s]
            GasDynamicsFrequency=1/((evalin('base','time_to_EW')/1000)+timefromendwall);
            [BifurcationHeight]=PredictBifurcation(evalin('base','Mout'), evalin('base','gammaOut'), MW_mix); %Predict Bifurcation & predict height

            DiagnosticsUsed=[0 0 0 0 0 0 0 0];% Change these values to 1 as dianostics are used in if statements below.  If the value remains zero, populate diagnostic-specific parameters with 'NA'.
            switch app.Ch4_diagnostic.Value
                case 'Kistler PXD'
                    KistlerSpecs=[str2double(app.Ch0_sensor_freq.Value), 0.00508, 20, EndwalltoTestSection]; %[Hz, m, psi/V, m], [Sample Freq, diam, conversion, location]
                    [Kistler_Voltage, pressureLP, t_ignPIndex, IDT_P, IDT_P_bias, StartIncidentShockSearch, StopReflectedShockSearch, comments]=MlappPressureTrace(evalin('base','Reacting'),str2double(app.PretriggermsTextArea.Value),evalin('base','Pout'),(evalin('base','time_to_EW')/1000), timefromendwall, evalin('base','darkPressureVolts'),evalin('base','time'),evalin('base','pressureVolts'),KistlerSpecs,evalin('base','Uis'),BifurcationHeight,str2double(app.OScopeFreqHzTextArea.Value),comments);
                    %[Ti]=TiFromPi(evalin('base','time'), KistlerSpecs, evalin('base','Tout'), evalin('base','Pout'), evalin('base','gammaOut'), evalin('base','rhoOut'), UserInput{14}); %Estimate time-resolved temperature from pressure trace
                    DiagnosticsUsed (1)=1;

                    cla(app.UIAxes) %Clears Axes if something was plotted previously
                    app.UIAxes.Position = [501 15 716 527];
                    app.Image.Visible = 'off'; app.Port4Label.Visible = 'off';app.Port5Label.Visible = 'off';app.Port6Label.Visible = 'off';app.Port7Label.Visible = 'off';app.Port8Label.Visible = 'off';app.inEditField.Visible = 'off';app.inEditField_2.Visible = 'off';app.inEditField_3.Visible = 'off';app.inEditField_4.Visible = 'off';app.inEditField_5.Visible = 'off';
                    plot(app.UIAxes,evalin('base','time'),Kistler_Voltage*PxdPsiToVolts*0.068046,'DisplayName','1)pressure raw')
                    hold(app.UIAxes, 'on')
                    plot(app.UIAxes,evalin('base','time'),pressureLP*PxdPsiToVolts*0.068046,'DisplayName','2)pressure lp')
                    xlim(app.UIAxes,[0 10])
                    ylim(app.UIAxes,[0 max(Kistler_Voltage*PxdPsiToVolts*0.068046)])
                    legend(app.UIAxes)
                    set(app.UIAxes, 'XDir','normal') %gotta switch it back since Uis graph was reversed.
                    xlabel(app.UIAxes,'time [ms]')
                    ylabel(app.UIAxes,'Pressure [atm]')

                    IDT_P='NA';
                    IDT_P_bias='NA';

                case 'OH SW' %As written, this will produce errors whenever OH SW was taken, but HeNe wasn't. It assumes the HeNe frequency is listed in Ch2. That would correspond to a user error anyway because you can't have OH SW IDT without HeNe data.
                    if app.ReactingButton.Value == true %if this was a reacting test, take data
                        [absorb, absorb_lp, t_SW, t_incident, t_EW, spike_ind, comments]=MlappLasTrace('HeNe','None',evalin('base','Reacting'),str2double(app.PretriggermsTextArea.Value),evalin('base','time'), evalin('base','pressureVolts'), evalin('base','HeNePitch'), evalin('base','HeNeCatch'), evalin('base','darkHeNePitch'),evalin('base','darkHeNeCatch'),PxdPsiToVolts,(evalin('base','time_to_EW')/1000), timefromendwall, str2double(app.Ch2_sensor_freq.Value),str2double(app.OScopeFreqHzTextArea.Value),comments);
                        [emission, emissionLP,t_ignOH_SW, IDT_OH_SW, IDT_OH_SW_bias,comments]=MlappemissionTrace('OH SW',t_SW, str2double(app.PretriggermsTextArea.Value), evalin('base','time'),evalin('base','OH_SW'),str2double(app.Ch1_sensor_freq.Value),str2double(app.OScopeFreqHzTextArea.Value), spike_ind,comments);
                        DiagnosticsUsed(2)=1;

                        cla(app.UIAxes) %Clears Axes if something was plotted previously
                        app.UIAxes.Position = [501 15 716 527];
                        app.Image.Visible = 'off'; app.Port4Label.Visible = 'off';app.Port5Label.Visible = 'off';app.Port6Label.Visible = 'off';app.Port7Label.Visible = 'off';app.Port8Label.Visible = 'off';app.inEditField.Visible = 'off';app.inEditField_2.Visible = 'off';app.inEditField_3.Visible = 'off';app.inEditField_4.Visible = 'off';app.inEditField_5.Visible = 'off';
                        hold(app.UIAxes, 'on')
                        plot(app.UIAxes,evalin('base','time'),emission,'DisplayName','OH SW')
                        plot(app.UIAxes,evalin('base','time'),emissionLP,'DisplayName','OH SW LP')
                        xlim(app.UIAxes,[1.5 5])
                        ylim(app.UIAxes,[0 1.5*max(emission)])
                        set(app.UIAxes, 'XDir','normal') %gotta switch it back since Uis graph was reversed.
                        xlabel(app.UIAxes,'time [ms]')
                        ylabel(app.UIAxes,'Volts [V]')
                        legend(app.UIAxes)

                    end %otherwise, do nothing
                case 'HeNe Pitch'
                    %Do Nothing. You'll do the LAS calcs next.
                    cla(app.UIAxes) %Clears Axes if something was plotted previously
                case 'HeNe Catch'
                    [absorbHeNe, absorb_lpHeNe, t_SW, t_incident, t_EW, spike_ind, comments]=MlappLasTrace('HeNe','None',evalin('base','Reacting'),str2double(app.PretriggermsTextArea.Value),evalin('base','time'), evalin('base','pressureVolts'), evalin('base','HeNePitch'), evalin('base','HeNeCatch'), evalin('base','darkHeNePitch'),evalin('base','darkHeNeCatch'),PxdPsiToVolts,(evalin('base','time_to_EW')/1000), timefromendwall, str2double(app.Ch2_sensor_freq.Value),str2double(app.OScopeFreqHzTextArea.Value),comments);
                    DiagnosticsUsed(4)=1;

                    cla(app.UIAxes) %Clears Axes if something was plotted previously
                    app.UIAxes.Position = [501 15 716 527];
                    app.Image.Visible = 'off'; app.Port4Label.Visible = 'off';app.Port5Label.Visible = 'off';app.Port6Label.Visible = 'off';app.Port7Label.Visible = 'off';app.Port8Label.Visible = 'off';app.inEditField.Visible = 'off';app.inEditField_2.Visible = 'off';app.inEditField_3.Visible = 'off';app.inEditField_4.Visible = 'off';app.inEditField_5.Visible = 'off';
                    hold(app.UIAxes, 'on')
                    plot(app.UIAxes,evalin('base','time'),absorbHeNe,'DisplayName','1)absorb HeNe raw')
                    plot(app.UIAxes,evalin('base','time'),absorb_lpHeNe,'DisplayName','2)absorb HeNe  lp')
                    xlim(app.UIAxes,[0 10])
                    ylim(app.UIAxes,[0 max(absorbHeNe)])
                    legend(app.UIAxes)
                    set(app.UIAxes, 'XDir','normal') %gotta switch it back since Uis graph was reversed.
                    xlabel(app.UIAxes,'time [ms]')
                    ylabel(app.UIAxes,'Absorbance [-ln(It/I0]')

                case 'OH EW'%As written, this will produce errors whenever OH SW was taken, but HeNe wasn't. It assumes the HeNe frequency is listed in Ch2. That would correspond to a user error anyway because you can't have OH SW IDT without HeNe data.
                    if app.ReactingButton.Value == true %if this was a reacting test, take data
                        [absorb, absorb_lp, t_SW, t_incident, t_EW, spike_ind, comments]=MlappLasTrace('HeNe','None',evalin('base','Reacting'),str2double(app.PretriggermsTextArea.Value),evalin('base','time'), evalin('base','pressureVolts'), evalin('base','HeNePitch'), evalin('base','HeNeCatch'), evalin('base','darkHeNePitch'),evalin('base','darkHeNeCatch'),PxdPsiToVolts,(evalin('base','time_to_EW')/1000), timefromendwall, str2double(app.Ch2_sensor_freq.Value),str2double(app.OScopeFreqHzTextArea.Value),comments);
                        [emission, emissionLP,t_ignOH_EW, IDT_OH_EW, IDT_OH_EW_bias,comments]=MlappemissionTrace('OH EW',t_EW, str2double(app.PretriggermsTextArea.Value), evalin('base','time'),evalin('base','OH_EW'),str2double(app.Ch1_sensor_freq.Value),str2double(app.OScopeFreqHzTextArea.Value), spike_ind,comments);
                        DiagnosticsUsed(5)=1;

                        cla(app.UIAxes) %Clears Axes if something was plotted previously
                        app.UIAxes.Position = [501 15 716 527];
                        app.Image.Visible = 'off'; app.Port4Label.Visible = 'off';app.Port5Label.Visible = 'off';app.Port6Label.Visible = 'off';app.Port7Label.Visible = 'off';app.Port8Label.Visible = 'off';app.inEditField.Visible = 'off';app.inEditField_2.Visible = 'off';app.inEditField_3.Visible = 'off';app.inEditField_4.Visible = 'off';app.inEditField_5.Visible = 'off';
                        hold(app.UIAxes, 'on')
                        plot(app.UIAxes,evalin('base','time'),emission,'DisplayName','OH EW')
                        plot(app.UIAxes,evalin('base','time'),emissionLP,'DisplayName','OH EW LP')
                        xlim(app.UIAxes,[1.5 5])
                        ylim(app.UIAxes,[min(emission) max(emissionLP)])
                        set(app.UIAxes, 'XDir','normal') %gotta switch it back since Uis graph was reversed.
                        xlabel(app.UIAxes,'time [ms]')
                        ylabel(app.UIAxes,'Volts [V]')
                        legend(app.UIAxes)

                    end %otherwise, do nothing
                case 'PCB 5'
                    %Do nothing.  Michael had some code for this, but I don't remember what for.
                    cla(app.UIAxes) %Clears Axes if something was plotted previously
                case 'CO2 Pitch'
                    %Do nothing.  You'll do the LAS calcs next.
                    cla(app.UIAxes) %Clears Axes if something was plotted previously
                case 'CO2 Catch' %Should really add a more transparent way to show that the filter frequency does not equal the sensor frequency shown in the GUI
                    [absorbCO2, absorb_lpCO2, t_SW, t_incident, t_EW, spike_ind, comments]=MlappLasTrace('CO2','Pinkowski_C2H4',evalin('base','Reacting'),str2double(app.PretriggermsTextArea.Value),evalin('base','time'), evalin('base','pressureVolts'), evalin('base','CO2Pitch'), evalin('base','CO2Catch'), evalin('base','darkCO2Pitch'),evalin('base','darkCO2Catch'),PxdPsiToVolts,(evalin('base','time_to_EW')/1000), timefromendwall,GasDynamicsFrequency, str2double(app.OScopeFreqHzTextArea.Value),comments);
                    DiagnosticsUsed(8)=1;

                    cla(app.UIAxes) %Clears Axes if something was plotted previously
                    app.UIAxes.Position = [501 15 716 527];
                    app.Image.Visible = 'off'; app.Port4Label.Visible = 'off';app.Port5Label.Visible = 'off';app.Port6Label.Visible = 'off';app.Port7Label.Visible = 'off';app.Port8Label.Visible = 'off';app.inEditField.Visible = 'off';app.inEditField_2.Visible = 'off';app.inEditField_3.Visible = 'off';app.inEditField_4.Visible = 'off';app.inEditField_5.Visible = 'off';
                    hold(app.UIAxes, 'on')
                    plot(app.UIAxes,evalin('base','time'),absorbCO2,'DisplayName','1)absorb CO2 raw')
                    plot(app.UIAxes,evalin('base','time'),absorb_lpCO2,'DisplayName','2)absorb CO2  lp')
                    xlim(app.UIAxes,[0 10])
                    ylim(app.UIAxes,[0 max(absorbCO2)])
                    legend(app.UIAxes)
                    set(app.UIAxes, 'XDir','normal') %gotta switch it back since Uis graph was reversed.
                    xlabel(app.UIAxes,'time [ms]')
                    ylabel(app.UIAxes,'Absorbance [-ln(It/I0]')
                otherwise
                    %Do nothing
            end
        end

        % Button pushed function: PlotCh5Button
        function PlotCh5ButtonPushed(app, event)
              EndwalltoTestSection=str2double(app.inEditField_7.Value)*0.0254;% Distance from Test Section centerline to endwall (Specified. Ref.2 CST-1006, CST-1007)
            PxdPsiToVolts=20; %psi/vo
            comments=' ';%Initializes comments string, which builds throughout the code.
            %UserInput = {app.dateTextArea.Value,app.RunTextArea.Value,'C2H4 O2 N2','32.72 131 500', 'He','y','y','y','y','y','2',evalin('base','solnmethod'),app.NASAFileTextArea.Value,evalin('base','ThermoState')}';
            %Test Gas Mole Fractions and Uncertainties from Driven Gas Table callback
            MW=cell2mat({app.DrivenGasTable.Data{10}, app.DrivenGasTable.Data{15}, app.DrivenGasTable.Data{20}, app.DrivenGasTable.Data{25}}); %converts array cells containing doubles to just a matrix of doubles
            MW_mix = sum(evalin('base','XtestGas').*MW);

            timefromendwall=EndwalltoTestSection/evalin('base','Urs'); %[s]
            GasDynamicsFrequency=1/((evalin('base','time_to_EW')/1000)+timefromendwall);
            [BifurcationHeight]=PredictBifurcation(evalin('base','Mout'), evalin('base','gammaOut'), MW_mix); %Predict Bifurcation & predict height

            DiagnosticsUsed=[0 0 0 0 0 0 0 0];% Change these values to 1 as dianostics are used in if statements below.  If the value remains zero, populate diagnostic-specific parameters with 'NA'.
            switch app.Ch5_diagnostic.Value
                case 'Kistler PXD'
                    KistlerSpecs=[str2double(app.Ch0_sensor_freq.Value), 0.00508, 20, EndwalltoTestSection]; %[Hz, m, psi/V, m], [Sample Freq, diam, conversion, location]
                    [Kistler_Voltage, pressureLP, t_ignPIndex, IDT_P, IDT_P_bias, StartIncidentShockSearch, StopReflectedShockSearch, comments]=MlappPressureTrace(evalin('base','Reacting'),str2double(app.PretriggermsTextArea.Value),evalin('base','Pout'),(evalin('base','time_to_EW')/1000), timefromendwall, evalin('base','darkPressureVolts'),evalin('base','time'),evalin('base','pressureVolts'),KistlerSpecs,evalin('base','Uis'),BifurcationHeight,str2double(app.OScopeFreqHzTextArea.Value),comments);
                    %[Ti]=TiFromPi(evalin('base','time'), KistlerSpecs, evalin('base','Tout'), evalin('base','Pout'), evalin('base','gammaOut'), evalin('base','rhoOut'), UserInput{14}); %Estimate time-resolved temperature from pressure trace
                    DiagnosticsUsed (1)=1;

                    cla(app.UIAxes) %Clears Axes if something was plotted previously
                    app.UIAxes.Position = [501 15 716 527];
                    app.Image.Visible = 'off'; app.Port4Label.Visible = 'off';app.Port5Label.Visible = 'off';app.Port6Label.Visible = 'off';app.Port7Label.Visible = 'off';app.Port8Label.Visible = 'off';app.inEditField.Visible = 'off';app.inEditField_2.Visible = 'off';app.inEditField_3.Visible = 'off';app.inEditField_4.Visible = 'off';app.inEditField_5.Visible = 'off';
                    plot(app.UIAxes,evalin('base','time'),Kistler_Voltage*PxdPsiToVolts*0.068046,'DisplayName','1)pressure raw')
                    hold(app.UIAxes, 'on')
                    plot(app.UIAxes,evalin('base','time'),pressureLP*PxdPsiToVolts*0.068046,'DisplayName','2)pressure lp')
                    xlim(app.UIAxes,[0 10])
                    ylim(app.UIAxes,[0 max(Kistler_Voltage*PxdPsiToVolts*0.068046)])
                    legend(app.UIAxes)
                    set(app.UIAxes, 'XDir','normal') %gotta switch it back since Uis graph was reversed.
                    xlabel(app.UIAxes,'time [ms]')
                    ylabel(app.UIAxes,'Pressure [atm]')

                    IDT_P='NA';
                    IDT_P_bias='NA';

                case 'OH SW' %As written, this will produce errors whenever OH SW was taken, but HeNe wasn't. It assumes the HeNe frequency is listed in Ch2. That would correspond to a user error anyway because you can't have OH SW IDT without HeNe data.
                    if app.ReactingButton.Value == true %if this was a reacting test, take data
                        [absorb, absorb_lp, t_SW, t_incident, t_EW, spike_ind, comments]=MlappLasTrace('HeNe','None',evalin('base','Reacting'),str2double(app.PretriggermsTextArea.Value),evalin('base','time'), evalin('base','pressureVolts'), evalin('base','HeNePitch'), evalin('base','HeNeCatch'), evalin('base','darkHeNePitch'),evalin('base','darkHeNeCatch'),PxdPsiToVolts,(evalin('base','time_to_EW')/1000), timefromendwall, str2double(app.Ch2_sensor_freq.Value),str2double(app.OScopeFreqHzTextArea.Value),comments);
                        [emission, emissionLP,t_ignOH_SW, IDT_OH_SW, IDT_OH_SW_bias,comments]=MlappemissionTrace('OH SW',t_SW, str2double(app.PretriggermsTextArea.Value), evalin('base','time'),evalin('base','OH_SW'),str2double(app.Ch1_sensor_freq.Value),str2double(app.OScopeFreqHzTextArea.Value), spike_ind,comments);
                        DiagnosticsUsed(2)=1;

                        cla(app.UIAxes) %Clears Axes if something was plotted previously
                        app.UIAxes.Position = [501 15 716 527];
                        app.Image.Visible = 'off'; app.Port4Label.Visible = 'off';app.Port5Label.Visible = 'off';app.Port6Label.Visible = 'off';app.Port7Label.Visible = 'off';app.Port8Label.Visible = 'off';app.inEditField.Visible = 'off';app.inEditField_2.Visible = 'off';app.inEditField_3.Visible = 'off';app.inEditField_4.Visible = 'off';app.inEditField_5.Visible = 'off';
                        hold(app.UIAxes, 'on')
                        plot(app.UIAxes,evalin('base','time'),emission,'DisplayName','OH SW')
                        plot(app.UIAxes,evalin('base','time'),emissionLP,'DisplayName','OH SW LP')
                        xlim(app.UIAxes,[1.5 5])
                        ylim(app.UIAxes,[0 1.5*max(emission)])
                        set(app.UIAxes, 'XDir','normal') %gotta switch it back since Uis graph was reversed.
                        xlabel(app.UIAxes,'time [ms]')
                        ylabel(app.UIAxes,'Volts [V]')
                        legend(app.UIAxes)

                    end %otherwise, do nothing
                case 'HeNe Pitch'
                    %Do Nothing. You'll do the LAS calcs next.
                    cla(app.UIAxes) %Clears Axes if something was plotted previously
                case 'HeNe Catch'
                    [absorbHeNe, absorb_lpHeNe, t_SW, t_incident, t_EW, spike_ind, comments]=MlappLasTrace('HeNe','None',evalin('base','Reacting'),str2double(app.PretriggermsTextArea.Value),evalin('base','time'), evalin('base','pressureVolts'), evalin('base','HeNePitch'), evalin('base','HeNeCatch'), evalin('base','darkHeNePitch'),evalin('base','darkHeNeCatch'),PxdPsiToVolts,(evalin('base','time_to_EW')/1000), timefromendwall, str2double(app.Ch2_sensor_freq.Value),str2double(app.OScopeFreqHzTextArea.Value),comments);
                    DiagnosticsUsed(4)=1;

                    cla(app.UIAxes) %Clears Axes if something was plotted previously
                    app.UIAxes.Position = [501 15 716 527];
                    app.Image.Visible = 'off'; app.Port4Label.Visible = 'off';app.Port5Label.Visible = 'off';app.Port6Label.Visible = 'off';app.Port7Label.Visible = 'off';app.Port8Label.Visible = 'off';app.inEditField.Visible = 'off';app.inEditField_2.Visible = 'off';app.inEditField_3.Visible = 'off';app.inEditField_4.Visible = 'off';app.inEditField_5.Visible = 'off';
                    hold(app.UIAxes, 'on')
                    plot(app.UIAxes,evalin('base','time'),absorbHeNe,'DisplayName','1)absorb HeNe raw')
                    plot(app.UIAxes,evalin('base','time'),absorb_lpHeNe,'DisplayName','2)absorb HeNe  lp')
                    xlim(app.UIAxes,[0 10])
                    ylim(app.UIAxes,[0 max(absorbHeNe)])
                    legend(app.UIAxes)
                    set(app.UIAxes, 'XDir','normal') %gotta switch it back since Uis graph was reversed.
                    xlabel(app.UIAxes,'time [ms]')
                    ylabel(app.UIAxes,'Absorbance [-ln(It/I0]')

                case 'OH EW'%As written, this will produce errors whenever OH SW was taken, but HeNe wasn't. It assumes the HeNe frequency is listed in Ch2. That would correspond to a user error anyway because you can't have OH SW IDT without HeNe data.
                    if app.ReactingButton.Value == true %if this was a reacting test, take data
                        [absorb, absorb_lp, t_SW, t_incident, t_EW, spike_ind, comments]=MlappLasTrace('HeNe','None',evalin('base','Reacting'),str2double(app.PretriggermsTextArea.Value),evalin('base','time'), evalin('base','pressureVolts'), evalin('base','HeNePitch'), evalin('base','HeNeCatch'), evalin('base','darkHeNePitch'),evalin('base','darkHeNeCatch'),PxdPsiToVolts,(evalin('base','time_to_EW')/1000), timefromendwall, str2double(app.Ch2_sensor_freq.Value),str2double(app.OScopeFreqHzTextArea.Value),comments);
                        [emission, emissionLP,t_ignOH_EW, IDT_OH_EW, IDT_OH_EW_bias,comments]=MlappemissionTrace('OH EW',t_EW, str2double(app.PretriggermsTextArea.Value), evalin('base','time'),evalin('base','OH_EW'),str2double(app.Ch1_sensor_freq.Value),str2double(app.OScopeFreqHzTextArea.Value), spike_ind,comments);
                        DiagnosticsUsed(5)=1;

                        cla(app.UIAxes) %Clears Axes if something was plotted previously
                        app.UIAxes.Position = [501 15 716 527];
                        app.Image.Visible = 'off'; app.Port4Label.Visible = 'off';app.Port5Label.Visible = 'off';app.Port6Label.Visible = 'off';app.Port7Label.Visible = 'off';app.Port8Label.Visible = 'off';app.inEditField.Visible = 'off';app.inEditField_2.Visible = 'off';app.inEditField_3.Visible = 'off';app.inEditField_4.Visible = 'off';app.inEditField_5.Visible = 'off';
                        hold(app.UIAxes, 'on')
                        plot(app.UIAxes,evalin('base','time'),emission,'DisplayName','OH EW')
                        plot(app.UIAxes,evalin('base','time'),emissionLP,'DisplayName','OH EW LP')
                        xlim(app.UIAxes,[1.5 5])
                        ylim(app.UIAxes,[min(emission) max(emissionLP)])
                        set(app.UIAxes, 'XDir','normal') %gotta switch it back since Uis graph was reversed.
                        xlabel(app.UIAxes,'time [ms]')
                        ylabel(app.UIAxes,'Volts [V]')
                        legend(app.UIAxes)

                    end %otherwise, do nothing
                case 'PCB 5'
                    %Do nothing.  Michael had some code for this, but I don't remember what for.
                    cla(app.UIAxes) %Clears Axes if something was plotted previously
                case 'CO2 Pitch'
                    %Do nothing.  You'll do the LAS calcs next.
                    cla(app.UIAxes) %Clears Axes if something was plotted previously
                case 'CO2 Catch' %Should really add a more transparent way to show that the filter frequency does not equal the sensor frequency shown in the GUI
                    [absorbCO2, absorb_lpCO2, t_SW, t_incident, t_EW, spike_ind, comments]=MlappLasTrace('CO2','Pinkowski_C2H4',evalin('base','Reacting'),str2double(app.PretriggermsTextArea.Value),evalin('base','time'), evalin('base','pressureVolts'), evalin('base','CO2Pitch'), evalin('base','CO2Catch'), evalin('base','darkCO2Pitch'),evalin('base','darkCO2Catch'),PxdPsiToVolts,(evalin('base','time_to_EW')/1000), timefromendwall,GasDynamicsFrequency, str2double(app.OScopeFreqHzTextArea.Value),comments);
                    DiagnosticsUsed(8)=1;

                    cla(app.UIAxes) %Clears Axes if something was plotted previously
                    app.UIAxes.Position = [501 15 716 527];
                    app.Image.Visible = 'off'; app.Port4Label.Visible = 'off';app.Port5Label.Visible = 'off';app.Port6Label.Visible = 'off';app.Port7Label.Visible = 'off';app.Port8Label.Visible = 'off';app.inEditField.Visible = 'off';app.inEditField_2.Visible = 'off';app.inEditField_3.Visible = 'off';app.inEditField_4.Visible = 'off';app.inEditField_5.Visible = 'off';
                    hold(app.UIAxes, 'on')
                    plot(app.UIAxes,evalin('base','time'),absorbCO2,'DisplayName','1)absorb CO2 raw')
                    plot(app.UIAxes,evalin('base','time'),absorb_lpCO2,'DisplayName','2)absorb CO2  lp')
                    xlim(app.UIAxes,[0 10])
                    ylim(app.UIAxes,[0 max(absorbCO2)])
                    legend(app.UIAxes)
                    set(app.UIAxes, 'XDir','normal') %gotta switch it back since Uis graph was reversed.
                    xlabel(app.UIAxes,'time [ms]')
                    ylabel(app.UIAxes,'Absorbance [-ln(It/I0]')
                otherwise
                    %Do nothing
            end
        end

        % Button pushed function: PlotCh6Button
        function PlotCh6ButtonPushed(app, event)
             EndwalltoTestSection=str2double(app.inEditField_7.Value)*0.0254;% Distance from Test Section centerline to endwall (Specified. Ref.2 CST-1006, CST-1007)
            PxdPsiToVolts=20; %psi/vo
            comments=' ';%Initializes comments string, which builds throughout the code.
            %UserInput = {app.dateTextArea.Value,app.RunTextArea.Value,'C2H4 O2 N2','32.72 131 500', 'He','y','y','y','y','y','2',evalin('base','solnmethod'),app.NASAFileTextArea.Value,evalin('base','ThermoState')}';
            %Test Gas Mole Fractions and Uncertainties from Driven Gas Table callback
            MW=cell2mat({app.DrivenGasTable.Data{10}, app.DrivenGasTable.Data{15}, app.DrivenGasTable.Data{20}, app.DrivenGasTable.Data{25}}); %converts array cells containing doubles to just a matrix of doubles
            MW_mix = sum(evalin('base','XtestGas').*MW);

            timefromendwall=EndwalltoTestSection/evalin('base','Urs'); %[s]
            GasDynamicsFrequency=1/((evalin('base','time_to_EW')/1000)+timefromendwall);
            [BifurcationHeight]=PredictBifurcation(evalin('base','Mout'), evalin('base','gammaOut'), MW_mix); %Predict Bifurcation & predict height

            DiagnosticsUsed=[0 0 0 0 0 0 0 0];% Change these values to 1 as dianostics are used in if statements below.  If the value remains zero, populate diagnostic-specific parameters with 'NA'.
            switch app.Ch6_diagnostic.Value
                case 'Kistler PXD'
                    KistlerSpecs=[str2double(app.Ch0_sensor_freq.Value), 0.00508, 20, EndwalltoTestSection]; %[Hz, m, psi/V, m], [Sample Freq, diam, conversion, location]
                    [Kistler_Voltage, pressureLP, t_ignPIndex, IDT_P, IDT_P_bias, StartIncidentShockSearch, StopReflectedShockSearch, comments]=MlappPressureTrace(evalin('base','Reacting'),str2double(app.PretriggermsTextArea.Value),evalin('base','Pout'),(evalin('base','time_to_EW')/1000), timefromendwall, evalin('base','darkPressureVolts'),evalin('base','time'),evalin('base','pressureVolts'),KistlerSpecs,evalin('base','Uis'),BifurcationHeight,str2double(app.OScopeFreqHzTextArea.Value),comments);
                    %[Ti]=TiFromPi(evalin('base','time'), KistlerSpecs, evalin('base','Tout'), evalin('base','Pout'), evalin('base','gammaOut'), evalin('base','rhoOut'), UserInput{14}); %Estimate time-resolved temperature from pressure trace
                    DiagnosticsUsed (1)=1;

                    cla(app.UIAxes) %Clears Axes if something was plotted previously
                    app.UIAxes.Position = [501 15 716 527];
                    app.Image.Visible = 'off'; app.Port4Label.Visible = 'off';app.Port5Label.Visible = 'off';app.Port6Label.Visible = 'off';app.Port7Label.Visible = 'off';app.Port8Label.Visible = 'off';app.inEditField.Visible = 'off';app.inEditField_2.Visible = 'off';app.inEditField_3.Visible = 'off';app.inEditField_4.Visible = 'off';app.inEditField_5.Visible = 'off';
                    plot(app.UIAxes,evalin('base','time'),Kistler_Voltage*PxdPsiToVolts*0.068046,'DisplayName','1)pressure raw')
                    hold(app.UIAxes, 'on')
                    plot(app.UIAxes,evalin('base','time'),pressureLP*PxdPsiToVolts*0.068046,'DisplayName','2)pressure lp')
                    xlim(app.UIAxes,[0 10])
                    ylim(app.UIAxes,[0 max(Kistler_Voltage*PxdPsiToVolts*0.068046)])
                    legend(app.UIAxes)
                    set(app.UIAxes, 'XDir','normal') %gotta switch it back since Uis graph was reversed.
                    xlabel(app.UIAxes,'time [ms]')
                    ylabel(app.UIAxes,'Pressure [atm]')

                    IDT_P='NA';
                    IDT_P_bias='NA';

                case 'OH SW' %As written, this will produce errors whenever OH SW was taken, but HeNe wasn't. It assumes the HeNe frequency is listed in Ch2. That would correspond to a user error anyway because you can't have OH SW IDT without HeNe data.
                    if app.ReactingButton.Value == true %if this was a reacting test, take data
                        [absorb, absorb_lp, t_SW, t_incident, t_EW, spike_ind, comments]=MlappLasTrace('HeNe','None',evalin('base','Reacting'),str2double(app.PretriggermsTextArea.Value),evalin('base','time'), evalin('base','pressureVolts'), evalin('base','HeNePitch'), evalin('base','HeNeCatch'), evalin('base','darkHeNePitch'),evalin('base','darkHeNeCatch'),PxdPsiToVolts,(evalin('base','time_to_EW')/1000), timefromendwall, str2double(app.Ch2_sensor_freq.Value),str2double(app.OScopeFreqHzTextArea.Value),comments);
                        [emission, emissionLP,t_ignOH_SW, IDT_OH_SW, IDT_OH_SW_bias,comments]=MlappemissionTrace('OH SW',t_SW, str2double(app.PretriggermsTextArea.Value), evalin('base','time'),evalin('base','OH_SW'),str2double(app.Ch1_sensor_freq.Value),str2double(app.OScopeFreqHzTextArea.Value), spike_ind,comments);
                        DiagnosticsUsed(2)=1;

                        cla(app.UIAxes) %Clears Axes if something was plotted previously
                        app.UIAxes.Position = [501 15 716 527];
                        app.Image.Visible = 'off'; app.Port4Label.Visible = 'off';app.Port5Label.Visible = 'off';app.Port6Label.Visible = 'off';app.Port7Label.Visible = 'off';app.Port8Label.Visible = 'off';app.inEditField.Visible = 'off';app.inEditField_2.Visible = 'off';app.inEditField_3.Visible = 'off';app.inEditField_4.Visible = 'off';app.inEditField_5.Visible = 'off';
                        hold(app.UIAxes, 'on')
                        plot(app.UIAxes,evalin('base','time'),emission,'DisplayName','OH SW')
                        plot(app.UIAxes,evalin('base','time'),emissionLP,'DisplayName','OH SW LP')
                        xlim(app.UIAxes,[1.5 5])
                        ylim(app.UIAxes,[0 1.5*max(emission)])
                        set(app.UIAxes, 'XDir','normal') %gotta switch it back since Uis graph was reversed.
                        xlabel(app.UIAxes,'time [ms]')
                        ylabel(app.UIAxes,'Volts [V]')
                        legend(app.UIAxes)

                    end %otherwise, do nothing
                case 'HeNe Pitch'
                    %Do Nothing. You'll do the LAS calcs next.
                    cla(app.UIAxes) %Clears Axes if something was plotted previously
                case 'HeNe Catch'
                    [absorbHeNe, absorb_lpHeNe, t_SW, t_incident, t_EW, spike_ind, comments]=MlappLasTrace('HeNe','None',evalin('base','Reacting'),str2double(app.PretriggermsTextArea.Value),evalin('base','time'), evalin('base','pressureVolts'), evalin('base','HeNePitch'), evalin('base','HeNeCatch'), evalin('base','darkHeNePitch'),evalin('base','darkHeNeCatch'),PxdPsiToVolts,(evalin('base','time_to_EW')/1000), timefromendwall, str2double(app.Ch2_sensor_freq.Value),str2double(app.OScopeFreqHzTextArea.Value),comments);
                    DiagnosticsUsed(4)=1;

                    cla(app.UIAxes) %Clears Axes if something was plotted previously
                    app.UIAxes.Position = [501 15 716 527];
                    app.Image.Visible = 'off'; app.Port4Label.Visible = 'off';app.Port5Label.Visible = 'off';app.Port6Label.Visible = 'off';app.Port7Label.Visible = 'off';app.Port8Label.Visible = 'off';app.inEditField.Visible = 'off';app.inEditField_2.Visible = 'off';app.inEditField_3.Visible = 'off';app.inEditField_4.Visible = 'off';app.inEditField_5.Visible = 'off';
                    hold(app.UIAxes, 'on')
                    plot(app.UIAxes,evalin('base','time'),absorbHeNe,'DisplayName','1)absorb HeNe raw')
                    plot(app.UIAxes,evalin('base','time'),absorb_lpHeNe,'DisplayName','2)absorb HeNe  lp')
                    xlim(app.UIAxes,[0 10])
                    ylim(app.UIAxes,[0 max(absorbHeNe)])
                    legend(app.UIAxes)
                    set(app.UIAxes, 'XDir','normal') %gotta switch it back since Uis graph was reversed.
                    xlabel(app.UIAxes,'time [ms]')
                    ylabel(app.UIAxes,'Absorbance [-ln(It/I0]')

                case 'OH EW'%As written, this will produce errors whenever OH SW was taken, but HeNe wasn't. It assumes the HeNe frequency is listed in Ch2. That would correspond to a user error anyway because you can't have OH SW IDT without HeNe data.
                    if app.ReactingButton.Value == true %if this was a reacting test, take data
                        [absorb, absorb_lp, t_SW, t_incident, t_EW, spike_ind, comments]=MlappLasTrace('HeNe','None',evalin('base','Reacting'),str2double(app.PretriggermsTextArea.Value),evalin('base','time'), evalin('base','pressureVolts'), evalin('base','HeNePitch'), evalin('base','HeNeCatch'), evalin('base','darkHeNePitch'),evalin('base','darkHeNeCatch'),PxdPsiToVolts,(evalin('base','time_to_EW')/1000), timefromendwall, str2double(app.Ch2_sensor_freq.Value),str2double(app.OScopeFreqHzTextArea.Value),comments);
                        [emission, emissionLP,t_ignOH_EW, IDT_OH_EW, IDT_OH_EW_bias,comments]=MlappemissionTrace('OH EW',t_EW, str2double(app.PretriggermsTextArea.Value), evalin('base','time'),evalin('base','OH_EW'),str2double(app.Ch1_sensor_freq.Value),str2double(app.OScopeFreqHzTextArea.Value), spike_ind,comments);
                        DiagnosticsUsed(5)=1;

                        cla(app.UIAxes) %Clears Axes if something was plotted previously
                        app.UIAxes.Position = [501 15 716 527];
                        app.Image.Visible = 'off'; app.Port4Label.Visible = 'off';app.Port5Label.Visible = 'off';app.Port6Label.Visible = 'off';app.Port7Label.Visible = 'off';app.Port8Label.Visible = 'off';app.inEditField.Visible = 'off';app.inEditField_2.Visible = 'off';app.inEditField_3.Visible = 'off';app.inEditField_4.Visible = 'off';app.inEditField_5.Visible = 'off';
                        hold(app.UIAxes, 'on')
                        plot(app.UIAxes,evalin('base','time'),emission,'DisplayName','OH EW')
                        plot(app.UIAxes,evalin('base','time'),emissionLP,'DisplayName','OH EW LP')
                        xlim(app.UIAxes,[1.5 5])
                        ylim(app.UIAxes,[min(emission) max(emissionLP)])
                        set(app.UIAxes, 'XDir','normal') %gotta switch it back since Uis graph was reversed.
                        xlabel(app.UIAxes,'time [ms]')
                        ylabel(app.UIAxes,'Volts [V]')
                        legend(app.UIAxes)

                    end %otherwise, do nothing
                case 'PCB 5'
                    %Do nothing.  Michael had some code for this, but I don't remember what for.
                    cla(app.UIAxes) %Clears Axes if something was plotted previously
                case 'CO2 Pitch'
                    %Do nothing.  You'll do the LAS calcs next.
                    cla(app.UIAxes) %Clears Axes if something was plotted previously
                case 'CO2 Catch' %Should really add a more transparent way to show that the filter frequency does not equal the sensor frequency shown in the GUI
                    [absorbCO2, absorb_lpCO2, t_SW, t_incident, t_EW, spike_ind, comments]=MlappLasTrace('CO2','Pinkowski_C2H4',evalin('base','Reacting'),str2double(app.PretriggermsTextArea.Value),evalin('base','time'), evalin('base','pressureVolts'), evalin('base','CO2Pitch'), evalin('base','CO2Catch'), evalin('base','darkCO2Pitch'),evalin('base','darkCO2Catch'),PxdPsiToVolts,(evalin('base','time_to_EW')/1000), timefromendwall,GasDynamicsFrequency, str2double(app.OScopeFreqHzTextArea.Value),comments);
                    DiagnosticsUsed(8)=1;

                    cla(app.UIAxes) %Clears Axes if something was plotted previously
                    app.UIAxes.Position = [501 15 716 527];
                    app.Image.Visible = 'off'; app.Port4Label.Visible = 'off';app.Port5Label.Visible = 'off';app.Port6Label.Visible = 'off';app.Port7Label.Visible = 'off';app.Port8Label.Visible = 'off';app.inEditField.Visible = 'off';app.inEditField_2.Visible = 'off';app.inEditField_3.Visible = 'off';app.inEditField_4.Visible = 'off';app.inEditField_5.Visible = 'off';
                    hold(app.UIAxes, 'on')
                    plot(app.UIAxes,evalin('base','time'),absorbCO2,'DisplayName','1)absorb CO2 raw')
                    plot(app.UIAxes,evalin('base','time'),absorb_lpCO2,'DisplayName','2)absorb CO2  lp')
                    xlim(app.UIAxes,[0 10])
                    ylim(app.UIAxes,[0 max(absorbCO2)])
                    legend(app.UIAxes)
                    set(app.UIAxes, 'XDir','normal') %gotta switch it back since Uis graph was reversed.
                    xlabel(app.UIAxes,'time [ms]')
                    ylabel(app.UIAxes,'Absorbance [-ln(It/I0]')
                otherwise
                    %Do nothing
            end
        end

        % Button pushed function: PlotCh7Button
        function PlotCh7ButtonPushed(app, event)
             EndwalltoTestSection=str2double(app.inEditField_7.Value)*0.0254;% Distance from Test Section centerline to endwall (Specified. Ref.2 CST-1006, CST-1007)
            PxdPsiToVolts=20; %psi/vo
            comments=' ';%Initializes comments string, which builds throughout the code.
            %UserInput = {app.dateTextArea.Value,app.RunTextArea.Value,'C2H4 O2 N2','32.72 131 500', 'He','y','y','y','y','y','2',evalin('base','solnmethod'),app.NASAFileTextArea.Value,evalin('base','ThermoState')}';
            %Test Gas Mole Fractions and Uncertainties from Driven Gas Table callback
            MW=cell2mat({app.DrivenGasTable.Data{10}, app.DrivenGasTable.Data{15}, app.DrivenGasTable.Data{20}, app.DrivenGasTable.Data{25}}); %converts array cells containing doubles to just a matrix of doubles
            MW_mix = sum(evalin('base','XtestGas').*MW);

            timefromendwall=EndwalltoTestSection/evalin('base','Urs'); %[s]
            GasDynamicsFrequency=1/((evalin('base','time_to_EW')/1000)+timefromendwall);
            [BifurcationHeight]=PredictBifurcation(evalin('base','Mout'), evalin('base','gammaOut'), MW_mix); %Predict Bifurcation & predict height

            DiagnosticsUsed=[0 0 0 0 0 0 0 0];% Change these values to 1 as dianostics are used in if statements below.  If the value remains zero, populate diagnostic-specific parameters with 'NA'.
            switch app.Ch7_diagnostic.Value
                case 'Kistler PXD'
                    KistlerSpecs=[str2double(app.Ch0_sensor_freq.Value), 0.00508, 20, EndwalltoTestSection]; %[Hz, m, psi/V, m], [Sample Freq, diam, conversion, location]
                    [Kistler_Voltage, pressureLP, t_ignPIndex, IDT_P, IDT_P_bias, StartIncidentShockSearch, StopReflectedShockSearch, comments]=MlappPressureTrace(evalin('base','Reacting'),str2double(app.PretriggermsTextArea.Value),evalin('base','Pout'),(evalin('base','time_to_EW')/1000), timefromendwall, evalin('base','darkPressureVolts'),evalin('base','time'),evalin('base','pressureVolts'),KistlerSpecs,evalin('base','Uis'),BifurcationHeight,str2double(app.OScopeFreqHzTextArea.Value),comments);
                    %[Ti]=TiFromPi(evalin('base','time'), KistlerSpecs, evalin('base','Tout'), evalin('base','Pout'), evalin('base','gammaOut'), evalin('base','rhoOut'), UserInput{14}); %Estimate time-resolved temperature from pressure trace
                    DiagnosticsUsed (1)=1;

                    cla(app.UIAxes) %Clears Axes if something was plotted previously
                    app.UIAxes.Position = [501 15 716 527];
                    app.Image.Visible = 'off'; app.Port4Label.Visible = 'off';app.Port5Label.Visible = 'off';app.Port6Label.Visible = 'off';app.Port7Label.Visible = 'off';app.Port8Label.Visible = 'off';app.inEditField.Visible = 'off';app.inEditField_2.Visible = 'off';app.inEditField_3.Visible = 'off';app.inEditField_4.Visible = 'off';app.inEditField_5.Visible = 'off';
                    plot(app.UIAxes,evalin('base','time'),Kistler_Voltage*PxdPsiToVolts*0.068046,'DisplayName','1)pressure raw')
                    hold(app.UIAxes, 'on')
                    plot(app.UIAxes,evalin('base','time'),pressureLP*PxdPsiToVolts*0.068046,'DisplayName','2)pressure lp')
                    xlim(app.UIAxes,[0 10])
                    ylim(app.UIAxes,[0 max(Kistler_Voltage*PxdPsiToVolts*0.068046)])
                    legend(app.UIAxes)
                    set(app.UIAxes, 'XDir','normal') %gotta switch it back since Uis graph was reversed.
                    xlabel(app.UIAxes,'time [ms]')
                    ylabel(app.UIAxes,'Pressure [atm]')

                    IDT_P='NA';
                    IDT_P_bias='NA';

                case 'OH SW' %As written, this will produce errors whenever OH SW was taken, but HeNe wasn't. It assumes the HeNe frequency is listed in Ch2. That would correspond to a user error anyway because you can't have OH SW IDT without HeNe data.
                    if app.ReactingButton.Value == true %if this was a reacting test, take data
                        [absorb, absorb_lp, t_SW, t_incident, t_EW, spike_ind, comments]=MlappLasTrace('HeNe','None',evalin('base','Reacting'),str2double(app.PretriggermsTextArea.Value),evalin('base','time'), evalin('base','pressureVolts'), evalin('base','HeNePitch'), evalin('base','HeNeCatch'), evalin('base','darkHeNePitch'),evalin('base','darkHeNeCatch'),PxdPsiToVolts,(evalin('base','time_to_EW')/1000), timefromendwall, str2double(app.Ch2_sensor_freq.Value),str2double(app.OScopeFreqHzTextArea.Value),comments);
                        [emission, emissionLP,t_ignOH_SW, IDT_OH_SW, IDT_OH_SW_bias,comments]=MlappemissionTrace('OH SW',t_SW, str2double(app.PretriggermsTextArea.Value), evalin('base','time'),evalin('base','OH_SW'),str2double(app.Ch1_sensor_freq.Value),str2double(app.OScopeFreqHzTextArea.Value), spike_ind,comments);
                        DiagnosticsUsed(2)=1;

                        cla(app.UIAxes) %Clears Axes if something was plotted previously
                        app.UIAxes.Position = [501 15 716 527];
                        app.Image.Visible = 'off'; app.Port4Label.Visible = 'off';app.Port5Label.Visible = 'off';app.Port6Label.Visible = 'off';app.Port7Label.Visible = 'off';app.Port8Label.Visible = 'off';app.inEditField.Visible = 'off';app.inEditField_2.Visible = 'off';app.inEditField_3.Visible = 'off';app.inEditField_4.Visible = 'off';app.inEditField_5.Visible = 'off';
                        hold(app.UIAxes, 'on')
                        plot(app.UIAxes,evalin('base','time'),emission,'DisplayName','OH SW')
                        plot(app.UIAxes,evalin('base','time'),emissionLP,'DisplayName','OH SW LP')
                        xlim(app.UIAxes,[1.5 5])
                        ylim(app.UIAxes,[0 1.5*max(emission)])
                        set(app.UIAxes, 'XDir','normal') %gotta switch it back since Uis graph was reversed.
                        xlabel(app.UIAxes,'time [ms]')
                        ylabel(app.UIAxes,'Volts [V]')
                        legend(app.UIAxes)

                    end %otherwise, do nothing
                case 'HeNe Pitch'
                    %Do Nothing. You'll do the LAS calcs next.
                    cla(app.UIAxes) %Clears Axes if something was plotted previously
                case 'HeNe Catch'
                    [absorbHeNe, absorb_lpHeNe, t_SW, t_incident, t_EW, spike_ind, comments]=MlappLasTrace('HeNe','None',evalin('base','Reacting'),str2double(app.PretriggermsTextArea.Value),evalin('base','time'), evalin('base','pressureVolts'), evalin('base','HeNePitch'), evalin('base','HeNeCatch'), evalin('base','darkHeNePitch'),evalin('base','darkHeNeCatch'),PxdPsiToVolts,(evalin('base','time_to_EW')/1000), timefromendwall, str2double(app.Ch2_sensor_freq.Value),str2double(app.OScopeFreqHzTextArea.Value),comments);
                    DiagnosticsUsed(4)=1;

                    cla(app.UIAxes) %Clears Axes if something was plotted previously
                    app.UIAxes.Position = [501 15 716 527];
                    app.Image.Visible = 'off'; app.Port4Label.Visible = 'off';app.Port5Label.Visible = 'off';app.Port6Label.Visible = 'off';app.Port7Label.Visible = 'off';app.Port8Label.Visible = 'off';app.inEditField.Visible = 'off';app.inEditField_2.Visible = 'off';app.inEditField_3.Visible = 'off';app.inEditField_4.Visible = 'off';app.inEditField_5.Visible = 'off';
                    hold(app.UIAxes, 'on')
                    plot(app.UIAxes,evalin('base','time'),absorbHeNe,'DisplayName','1)absorb HeNe raw')
                    plot(app.UIAxes,evalin('base','time'),absorb_lpHeNe,'DisplayName','2)absorb HeNe  lp')
                    xlim(app.UIAxes,[0 10])
                    ylim(app.UIAxes,[0 max(absorbHeNe)])
                    legend(app.UIAxes)
                    set(app.UIAxes, 'XDir','normal') %gotta switch it back since Uis graph was reversed.
                    xlabel(app.UIAxes,'time [ms]')
                    ylabel(app.UIAxes,'Absorbance [-ln(It/I0]')

                case 'OH EW'%As written, this will produce errors whenever OH SW was taken, but HeNe wasn't. It assumes the HeNe frequency is listed in Ch2. That would correspond to a user error anyway because you can't have OH SW IDT without HeNe data.
                    if app.ReactingButton.Value == true %if this was a reacting test, take data
                        [absorb, absorb_lp, t_SW, t_incident, t_EW, spike_ind, comments]=MlappLasTrace('HeNe','None',evalin('base','Reacting'),str2double(app.PretriggermsTextArea.Value),evalin('base','time'), evalin('base','pressureVolts'), evalin('base','HeNePitch'), evalin('base','HeNeCatch'), evalin('base','darkHeNePitch'),evalin('base','darkHeNeCatch'),PxdPsiToVolts,(evalin('base','time_to_EW')/1000), timefromendwall, str2double(app.Ch2_sensor_freq.Value),str2double(app.OScopeFreqHzTextArea.Value),comments);
                        [emission, emissionLP,t_ignOH_EW, IDT_OH_EW, IDT_OH_EW_bias,comments]=MlappemissionTrace('OH EW',t_EW, str2double(app.PretriggermsTextArea.Value), evalin('base','time'),evalin('base','OH_EW'),str2double(app.Ch1_sensor_freq.Value),str2double(app.OScopeFreqHzTextArea.Value), spike_ind,comments);
                        DiagnosticsUsed(5)=1;

                        cla(app.UIAxes) %Clears Axes if something was plotted previously
                        app.UIAxes.Position = [501 15 716 527];
                        app.Image.Visible = 'off'; app.Port4Label.Visible = 'off';app.Port5Label.Visible = 'off';app.Port6Label.Visible = 'off';app.Port7Label.Visible = 'off';app.Port8Label.Visible = 'off';app.inEditField.Visible = 'off';app.inEditField_2.Visible = 'off';app.inEditField_3.Visible = 'off';app.inEditField_4.Visible = 'off';app.inEditField_5.Visible = 'off';
                        hold(app.UIAxes, 'on')
                        plot(app.UIAxes,evalin('base','time'),emission,'DisplayName','OH EW')
                        plot(app.UIAxes,evalin('base','time'),emissionLP,'DisplayName','OH EW LP')
                        xlim(app.UIAxes,[1.5 5])
                        ylim(app.UIAxes,[min(emission) max(emissionLP)])
                        set(app.UIAxes, 'XDir','normal') %gotta switch it back since Uis graph was reversed.
                        xlabel(app.UIAxes,'time [ms]')
                        ylabel(app.UIAxes,'Volts [V]')
                        legend(app.UIAxes)

                    end %otherwise, do nothing
                case 'PCB 5'
                    %Do nothing.  Michael had some code for this, but I don't remember what for.
                    cla(app.UIAxes) %Clears Axes if something was plotted previously
                case 'CO2 Pitch'
                    %Do nothing.  You'll do the LAS calcs next.
                    cla(app.UIAxes) %Clears Axes if something was plotted previously
                case 'CO2 Catch' %Should really add a more transparent way to show that the filter frequency does not equal the sensor frequency shown in the GUI
                    [absorbCO2, absorb_lpCO2, t_SW, t_incident, t_EW, spike_ind, comments]=MlappLasTrace('CO2','Pinkowski_C2H4',evalin('base','Reacting'),str2double(app.PretriggermsTextArea.Value),evalin('base','time'), evalin('base','pressureVolts'), evalin('base','CO2Pitch'), evalin('base','CO2Catch'), evalin('base','darkCO2Pitch'),evalin('base','darkCO2Catch'),PxdPsiToVolts,(evalin('base','time_to_EW')/1000), timefromendwall,GasDynamicsFrequency, str2double(app.OScopeFreqHzTextArea.Value),comments);
                    DiagnosticsUsed(8)=1;

                    cla(app.UIAxes) %Clears Axes if something was plotted previously
                    app.UIAxes.Position = [501 15 716 527];
                    app.Image.Visible = 'off'; app.Port4Label.Visible = 'off';app.Port5Label.Visible = 'off';app.Port6Label.Visible = 'off';app.Port7Label.Visible = 'off';app.Port8Label.Visible = 'off';app.inEditField.Visible = 'off';app.inEditField_2.Visible = 'off';app.inEditField_3.Visible = 'off';app.inEditField_4.Visible = 'off';app.inEditField_5.Visible = 'off';
                    hold(app.UIAxes, 'on')
                    plot(app.UIAxes,evalin('base','time'),absorbCO2,'DisplayName','1)absorb CO2 raw')
                    plot(app.UIAxes,evalin('base','time'),absorb_lpCO2,'DisplayName','2)absorb CO2  lp')
                    xlim(app.UIAxes,[0 10])
                    ylim(app.UIAxes,[0 max(absorbCO2)])
                    legend(app.UIAxes)
                    set(app.UIAxes, 'XDir','normal') %gotta switch it back since Uis graph was reversed.
                    xlabel(app.UIAxes,'time [ms]')
                    ylabel(app.UIAxes,'Absorbance [-ln(It/I0]')
                    assignin('base','absorb_lpCO2',absorb_lpCO2)
                otherwise
                    %Do nothing
            end
        end

        % Button pushed function: LoadRawDataPlotUisButton
        function LoadRawDataPlotUisButtonPushed(app, event)
            %Load and extract Data Files
            [CT_file,CT_data,shock_file,shock_data,dark_file,dark_data,EP_file,EP_data]=GetFiles(app.PathButton.Text, str2double(app.dateTextArea.Value), str2double(app.RunTextArea.Value));
            assignin('base','CT_file',CT_file)
            assignin('base','CT_data',CT_data)

            assignin('base','shock_file',shock_file)
            %assignin('base','shock_data',shock_data)
            assignin('base','time',shock_data(:,1))
            assignin('base','pressureVolts',shock_data(:,2))
            assignin('base','OH_SW',shock_data(:,3))
            assignin('base','HeNePitch',shock_data(:,4))
            assignin('base','HeNeCatch',shock_data(:,5))
            assignin('base','OH_EW',shock_data(:,6))
            assignin('base','PCB5',shock_data(:,7))
            assignin('base','CO2Pitch',shock_data(:,8))
            assignin('base','CO2Catch',shock_data(:,9))

            assignin('base','dark_file',dark_file)
            %assignin('base','dark_data',dark_data)
            assignin('base','darkPressureVolts',dark_data(:,2))
            assignin('base','darkOH_SW',dark_data(:,3))
            assignin('base','darkHeNePitch',dark_data(:,4))
            assignin('base','darkHeNeCatch',dark_data(:,5))
            assignin('base','darkOH_EW',dark_data(:,6))
            assignin('base','darkPCB5',dark_data(:,7))
            assignin('base','darkCO2Pitch',dark_data(:,8))
            assignin('base','darkCO2Catch',dark_data(:,9))

            assignin('base','EP_file',EP_file)
            assignin('base','EP_data',EP_data)


            %If "Plot Uis" button pushed, run Full Shock Shock Tube Data Reduction - Immediate DAQ Computer Postprocessing version

            %Define Constant physical parameters
            EndwalltoTestSection=str2double(app.inEditField_7.Value)*0.0254;% Distance from Test Section centerline to endwall (Specified. Ref.2 CST-1006, CST-1007)
            %     =[DM1_DM2     ,DM2_DM3      , DM3_DM4      , DM4_DM5      , DM5_DM6      , DM6_DM7
            DMdist=[12.00*0.0254,11.993*0.0254, str2double(app.inEditField.Value)*0.0254, str2double(app.inEditField_2.Value)*0.0254, str2double(app.inEditField_3.Value)*0.0254, str2double(app.inEditField_4.Value)*0.0254, str2double(app.inEditField_5.Value)*0.0254];% [m] Dead-mass ports center-to-center spacing (measured. See Table9 (P.34) Ref.1)
            firstDMpcbPort=3;
            comments=' ';%Initializes comments string, which builds throughout the code.

            %Incident Shock Velocity and Uncertainty
                        [EndwalltoMidpoints, U, xx, Upred, UpredCI, Uis, Uis_bias, time_to_EW, UatPCB, comments]=MlappShockVelocity(evalin('base','CT_data'),DMdist,evalin('base','numberOfCounters'),firstDMpcbPort,EndwalltoTestSection, comments);%ShockPassFrequency=Uis/WindowDiam; %[Hz] How fast the shock passes a test section window.

            cla(app.UIAxes) %Clears Axes if something was plotted previously
            %app.UIAxes.Position =[622,160,554,382];
            app.UIAxes.Position =[505,122,694,420];
            hold(app.UIAxes, 'on') %Traditional hold on statement, with no "hold off" because every graph uses "cla"
            for i=1:evalin('base','numberOfCounters')
            plot(app.UIAxes,EndwalltoMidpoints(i),U(i),'r O')
            end
            %plot(app.UIAxes,EndwalltoMidpoints(2),U(2),'r O')
            %plot(app.UIAxes,EndwalltoMidpoints(3),U(3),'r O')
            %plot(app.UIAxes,EndwalltoMidpoints(4),U(4),'r O')
            %plot(app.UIAxes,EndwalltoMidpoints(4),U(4),'r O')
            %plot(app.UIAxes,U_fit)
            %%line(xx,predict(U_wnlm,xx),'color','b','linestyle', '--')
            %plot(app.UIAxes,xx,Upred,'b--',xx, UpredCI, 'b--','DisplayName','WNLM Fit & CI')
            plot(app.UIAxes,0, Uis, 'b O', 'MarkerFaceColor', 'blue', 'DisplayName','Uis Endwall')
            plot(app.UIAxes,0, Uis+Uis_bias, 'b O', 'DisplayName','Uis Bias')
            plot(app.UIAxes,0, Uis-Uis_bias, 'b O', 'DisplayName','Uis Bias')
            set(app.UIAxes, 'XDir','reverse')
            xlabel(app.UIAxes,'Distance From Endwall [m]')
            ylabel(app.UIAxes,'Shock Velocity [m/s]')

            app.UismsTextArea.Value = num2str(Uis,'%.0f');
            assignin('base','Uis',Uis)
            assignin('base','Uis_bias',Uis_bias)
            T1 = EP_data(2,5)+273.15;
            assignin('base','T1',T1)
            P1 = EP_data(1,5);
            assignin('base','P1',P1)
            assignin('base','time_to_EW',time_to_EW)
        end

        % Selection changed function: FROSHSolnButtonGroup
        function FROSHSolnButtonGroupSelectionChanged(app, event)
            selectedButton = app.FROSHSolnButtonGroup.SelectedObject;
            if app.FFButton.Value==true
                solnmethod='FF';
            elseif app.EEButton.Value==true
                solnmethod='EE';
            else
                solnmethod='FE';
            end
            assignin('base','solnmethod',solnmethod)
        end

        % Selection changed function: State5AssumeButtonGroup
        function State5AssumeButtonGroupSelectionChanged(app, event)
            selectedButton = app.State5AssumeButtonGroup.SelectedObject;
            if app.isobaricButton.Value==true
                ThermoState='isobaric';
            elseif app.isochoricButton.Value==true
                ThermoState='isochoric';
            else
                ThermoState='isentropic';
            end
            assignin('base','ThermoState',ThermoState)
        end

        % Selection changed function: TestTypeButtonGroup
        function TestTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.TestTypeButtonGroup.SelectedObject;
            if app.ReactingButton.Value == true
                Reacting='y';
            else
                Reacting='n';
            end
            assignin('base','Reacting',Reacting)
        end

        % Value changed function: PCB2CheckBox
        function PCB2CheckBoxValueChanged(app, event)
            value = app.PCB2CheckBox.Value;
            %(evalin('base','NumberofCounters');
            assignin('base','PCB2Value',value)
        end

        % Value changed function: PCB3CheckBox
        function PCB3CheckBoxValueChanged(app, event)
            value = app.PCB3CheckBox.Value;
            assignin('base','PCB3Value',value)
        end

        % Value changed function: PCB4CheckBox
        function PCB4CheckBoxValueChanged(app, event)
            value = app.PCB4CheckBox.Value;
            assignin('base','PCB4Value',value)
        end

        % Value changed function: PCB5CheckBox
        function PCB5CheckBoxValueChanged(app, event)
            value = app.PCB5CheckBox.Value;
            assignin('base','PCB5Value',value)
        end

        % Value changed function: PCB6CheckBox
        function PCB6CheckBoxValueChanged(app, event)
            value = app.PCB6CheckBox.Value;
            assignin('base','PCB6Value',value)
        end

        % Value changed function: PCB7CheckBox
        function PCB7CheckBoxValueChanged(app, event)
            value = app.PCB7CheckBox.Value;
            assignin('base','PCB7Value',value)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ShockTubePostProcessorUIFigure = uifigure('Visible', 'off');
            app.ShockTubePostProcessorUIFigure.Name = 'ShockTubePostProcessor';
            app.UIAxes = uiaxes(app.ShockTubePostProcessorUIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @RunFROSHButtonPushed, true);
            app.Image = uiimage(app.ShockTubePostProcessorUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'CSTPicture1.png');
            app.OScopeFreqHzTextAreaLabel = uilabel(app.ShockTubePostProcessorUIFigure);
            app.OScopeFreqHzTextAreaLabel.Text = 'OScope Freq [Hz]';
            app.OScopeFreqHzTextArea = uitextarea(app.ShockTubePostProcessorUIFigure);
            app.OScopeFreqHzTextArea.ValueChangedFcn = createCallbackFcn(app, @ScopeFrequencyChanged, true);
            app.OScopeFreqHzTextArea.ValueChangingFcn = createCallbackFcn(app, @ScopeFrequencyChanged, true);
            app.OScopeFreqHzTextArea.Value = {'1000000'};
            app.DataPointsTextAreaLabel = uilabel(app.ShockTubePostProcessorUIFigure);
            app.DataPointsTextAreaLabel.Text = '#Data Points';
            app.DataPointsTextArea = uitextarea(app.ShockTubePostProcessorUIFigure);
            app.DataPointsTextArea.ValueChangedFcn = createCallbackFcn(app, @DataPointsChanged, true);
            app.DataPointsTextArea.ValueChangingFcn = createCallbackFcn(app, @DataPointsChanged, true);
            app.DataPointsTextArea.Value = {'100000'};
            app.PretriggermsTextAreaLabel = uilabel(app.ShockTubePostProcessorUIFigure);
            app.PretriggermsTextAreaLabel.Text = 'Pre-trigger [ms]';
            app.PretriggermsTextArea = uitextarea(app.ShockTubePostProcessorUIFigure);
            app.PretriggermsTextArea.Value = {'2'};
            app.TestTypeButtonGroup = uibuttongroup(app.ShockTubePostProcessorUIFigure);
            app.TestTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TestTypeButtonGroupSelectionChanged, true);
            app.TestTypeButtonGroup.Title = 'Test Type';
            app.ReactingButton = uiradiobutton(app.TestTypeButtonGroup);
            app.ReactingButton.Text = 'Reacting';
            app.ReactingButton.Value = true;
            app.NonreactingButton = uiradiobutton(app.TestTypeButtonGroup);
            app.NonreactingButton.Text = 'Nonreacting';
            app.RunTextAreaLabel = uilabel(app.ShockTubePostProcessorUIFigure);
            app.RunTextAreaLabel.Text = 'Run';
            app.RunTextArea = uitextarea(app.ShockTubePostProcessorUIFigure);
            app.RunTextArea.Value = {'001'};
            app.Ch0_used = uicheckbox(app.ShockTubePostProcessorUIFigure);
            app.Ch0_used.Text = '';
            app.Ch0_used.Value = true;
            app.Ch0_label = uilabel(app.ShockTubePostProcessorUIFigure);
            app.Ch0_label.Text = 'Ch0';
            app.Ch0_diagnostic = uidropdown(app.ShockTubePostProcessorUIFigure);
            app.Ch0_diagnostic.Items = {'Kistler PXD', 'OH SW', 'HeNe Pitch', 'HeNe Catch', 'OH EW', 'PCB 5', 'CO2 Pitch', 'CO2 Catch'};
            app.Ch0_diagnostic.ValueChangedFcn = createCallbackFcn(app, @Ch0_diagnosticValueChanged, true);
            app.Ch0_diagnostic.Value = 'Kistler PXD';
            app.Ch0_sensor_freq = uieditfield(app.ShockTubePostProcessorUIFigure, 'text');
            app.Ch0_sensor_freq.Value = '100000';
            app.Ch1_sensor_freq = uieditfield(app.ShockTubePostProcessorUIFigure, 'text');
            app.Ch1_sensor_freq.Value = '2600';
            app.Ch1_diagnostic = uidropdown(app.ShockTubePostProcessorUIFigure);
            app.Ch1_diagnostic.Items = {'Kistler PXD', 'OH SW', 'HeNe Pitch', 'HeNe Catch', 'OH EW', 'PCB 5', 'CO2 Pitch', 'CO2 Catch'};
            app.Ch1_diagnostic.ValueChangedFcn = createCallbackFcn(app, @Ch1_diagnosticValueChanged, true);
            app.Ch1_diagnostic.Value = 'OH SW';
            app.Ch1_used = uicheckbox(app.ShockTubePostProcessorUIFigure);
            app.Ch1_used.Text = '';
            app.Ch1_used.Value = true;
            app.Ch1_label = uilabel(app.ShockTubePostProcessorUIFigure);
            app.Ch1_label.Text = 'Ch1';
            app.Ch2_sensor_freq = uieditfield(app.ShockTubePostProcessorUIFigure, 'text');
            app.Ch2_sensor_freq.Value = '1000000';
            app.Ch2_diagnostic = uidropdown(app.ShockTubePostProcessorUIFigure);
            app.Ch2_diagnostic.Items = {'Kistler PXD', 'OH SW', 'HeNe Pitch', 'HeNe Catch', 'OH EW', 'PCB 5', 'CO2 Pitch', 'CO2 Catch'};
            app.Ch2_diagnostic.ValueChangedFcn = createCallbackFcn(app, @Ch2_diagnosticValueChanged, true);
            app.Ch2_diagnostic.Value = 'HeNe Pitch';
            app.Ch2_used = uicheckbox(app.ShockTubePostProcessorUIFigure);
            app.Ch2_used.Text = '';
            app.Ch2_used.Value = true;
            app.Ch2_label = uilabel(app.ShockTubePostProcessorUIFigure);
            app.Ch2_label.Text = 'Ch2';
            app.Ch3_sensor_freq = uieditfield(app.ShockTubePostProcessorUIFigure, 'text');
            app.Ch3_sensor_freq.Value = '1000000';
            app.Ch3_diagnostic = uidropdown(app.ShockTubePostProcessorUIFigure);
            app.Ch3_diagnostic.Items = {'Kistler PXD', 'OH SW', 'HeNe Pitch', 'HeNe Catch', 'OH EW', 'PCB 5', 'CO2 Pitch', 'CO2 Catch'};
            app.Ch3_diagnostic.ValueChangedFcn = createCallbackFcn(app, @Ch3_diagnosticValueChanged, true);
            app.Ch3_diagnostic.Value = 'HeNe Catch';
            app.Ch3_used = uicheckbox(app.ShockTubePostProcessorUIFigure);
            app.Ch3_used.Text = '';
            app.Ch3_used.Value = true;
            app.Ch3_label = uilabel(app.ShockTubePostProcessorUIFigure);
            app.Ch3_label.Text = 'Ch3';
            app.Ch4_sensor_freq = uieditfield(app.ShockTubePostProcessorUIFigure, 'text');
            app.Ch4_sensor_freq.Value = '100000';
            app.Ch4_diagnostic = uidropdown(app.ShockTubePostProcessorUIFigure);
            app.Ch4_diagnostic.Items = {'Kistler PXD', 'OH SW', 'HeNe Pitch', 'HeNe Catch', 'OH EW', 'PCB 5', 'CO2 Pitch', 'CO2 Catch'};
            app.Ch4_diagnostic.ValueChangedFcn = createCallbackFcn(app, @Ch4_diagnosticValueChanged, true);
            app.Ch4_diagnostic.Value = 'OH EW';
            app.Ch4_used = uicheckbox(app.ShockTubePostProcessorUIFigure);
            app.Ch4_used.Text = '';
            app.Ch4_used.Value = true;
            app.Ch4_label = uilabel(app.ShockTubePostProcessorUIFigure);
            app.Ch4_label.Text = 'Ch4';
            app.Ch5_sensor_freq = uieditfield(app.ShockTubePostProcessorUIFigure, 'text');
            app.Ch5_sensor_freq.Value = '100000';
            app.Ch5_diagnostic = uidropdown(app.ShockTubePostProcessorUIFigure);
            app.Ch5_diagnostic.Items = {'Kistler PXD', 'OH SW', 'HeNe Pitch', 'HeNe Catch', 'OH EW', 'PCB 5', 'CO2 Pitch', 'CO2 Catch'};
            app.Ch5_diagnostic.ValueChangedFcn = createCallbackFcn(app, @Ch5_diagnosticValueChanged, true);
            app.Ch5_diagnostic.Value = 'PCB 5';
            app.Ch5_used = uicheckbox(app.ShockTubePostProcessorUIFigure);
            app.Ch5_used.Text = '';
            app.Ch5_used.Value = true;
            app.Ch5_label = uilabel(app.ShockTubePostProcessorUIFigure);
            app.Ch5_label.Text = 'Ch5';
            app.Ch6_sensor_freq = uieditfield(app.ShockTubePostProcessorUIFigure, 'text');
            app.Ch6_sensor_freq.Value = '1000000';
            app.Ch6_diagnostic = uidropdown(app.ShockTubePostProcessorUIFigure);
            app.Ch6_diagnostic.Items = {'Kistler PXD', 'OH SW', 'HeNe Pitch', 'HeNe Catch', 'OH EW', 'PCB 5', 'CO2 Pitch', 'CO2 Catch'};
            app.Ch6_diagnostic.ValueChangedFcn = createCallbackFcn(app, @Ch6_diagnosticValueChanged, true);
            app.Ch6_diagnostic.Value = 'CO2 Pitch';
            app.Ch6_used = uicheckbox(app.ShockTubePostProcessorUIFigure);
            app.Ch6_used.Text = '';
            app.Ch6_used.Value = true;
            app.Ch6_label = uilabel(app.ShockTubePostProcessorUIFigure);
            app.Ch6_label.Text = 'Ch6';
            app.Ch7_sensor_freq = uieditfield(app.ShockTubePostProcessorUIFigure, 'text');
            app.Ch7_sensor_freq.Value = '1000000';
            app.Ch7_diagnostic = uidropdown(app.ShockTubePostProcessorUIFigure);
            app.Ch7_diagnostic.Items = {'Kistler PXD', 'OH SW', 'HeNe Pitch', 'HeNe Catch', 'OH EW', 'PCB 5', 'CO2 Pitch', 'CO2 Catch'};
            app.Ch7_diagnostic.ValueChangedFcn = createCallbackFcn(app, @Ch7_diagnosticValueChanged, true);
            app.Ch7_diagnostic.Value = 'CO2 Catch';
            app.Ch7_used = uicheckbox(app.ShockTubePostProcessorUIFigure);
            app.Ch7_used.Text = '';
            app.Ch7_used.Value = true;
            app.Ch7_label = uilabel(app.ShockTubePostProcessorUIFigure);
            app.Ch7_label.Text = 'Ch7';
            app.AcqTimemsTextAreaLabel = uilabel(app.ShockTubePostProcessorUIFigure);
            app.AcqTimemsTextAreaLabel.Text = 'Acq Time [ms]';
            app.AcqTimemsTextArea = uitextarea(app.ShockTubePostProcessorUIFigure);
            app.AcqTimemsTextArea.Editable = 'off';
            app.usedLabel = uilabel(app.ShockTubePostProcessorUIFigure);
            app.usedLabel.Text = 'used?';
            app.DiagnosticLabel = uilabel(app.ShockTubePostProcessorUIFigure);
            app.DiagnosticLabel.Text = 'Diagnostic';
            app.SensorFreqHzLabel = uilabel(app.ShockTubePostProcessorUIFigure);
            app.SensorFreqHzLabel.Text = 'Sensor Freq [Hz]';
            app.DriverGasLabel = uilabel(app.ShockTubePostProcessorUIFigure);
            app.DriverGasLabel.Text = 'Driver Gas';
            app.DrivenGasLabel = uilabel(app.ShockTubePostProcessorUIFigure);
            app.DrivenGasLabel.Text = 'Driven Gas';
            app.PathButton = uibutton(app.ShockTubePostProcessorUIFigure, 'push');
            app.PathButton.ButtonPushedFcn = createCallbackFcn(app, @PathButtonPushed, true);
            app.PathButton.Text = 'C:\Users\mitchell.hageman\OneDrive - afacademy.af.edu\Desktop\LabFolder - CST\Data\';
            app.Path = uilabel(app.ShockTubePostProcessorUIFigure);
            app.Path.Text = 'Path';
            app.DriverGasTable = uitable(app.ShockTubePostProcessorUIFigure);
            app.DriverGasTable.ColumnName = '';
            app.DriverGasTable.RowName = {};
            app.DriverGasTable.CellEditCallback = createCallbackFcn(app, @DriverGasTableCellEdit, true);
            app.DrivenGasTable = uitable(app.ShockTubePostProcessorUIFigure);
            app.DrivenGasTable.ColumnName = '';
            app.DrivenGasTable.RowName = {};
            app.DrivenGasTable.CellEditCallback = createCallbackFcn(app, @DrivenGasTableCellEdit, true);
            app.RunFROSHButton = uibutton(app.ShockTubePostProcessorUIFigure, 'push');
            app.RunFROSHButton.ButtonPushedFcn = createCallbackFcn(app, @RunFROSHButtonPushed, true);
            app.RunFROSHButton.Text = 'Run FROSH';
            app.Label = uilabel(app.ShockTubePostProcessorUIFigure);
            app.Label.Text = 'in';
            app.inEditField = uieditfield(app.ShockTubePostProcessorUIFigure, 'text');
            app.inEditField.Editable = 'off';
            app.inEditField.Value = '11.996';
            app.inEditField_2Label = uilabel(app.ShockTubePostProcessorUIFigure);
            app.inEditField_2Label.Text = 'in';
            app.inEditField_2 = uieditfield(app.ShockTubePostProcessorUIFigure, 'text');
            app.inEditField_2.Editable = 'off';
            app.inEditField_2.Value = '11.994';
            app.inEditField_3Label = uilabel(app.ShockTubePostProcessorUIFigure);
            app.inEditField_3Label.Text = 'in';
            app.inEditField_3 = uieditfield(app.ShockTubePostProcessorUIFigure, 'text');
            app.inEditField_3.Editable = 'off';
            app.inEditField_3.Value = '11.991';
            app.inEditField_4Label = uilabel(app.ShockTubePostProcessorUIFigure);
            app.inEditField_4Label.Text = 'in';
            app.inEditField_4 = uieditfield(app.ShockTubePostProcessorUIFigure, 'text');
            app.inEditField_4.Editable = 'off';
            app.inEditField_4.Value = '11.992';
            app.inEditField_5Label = uilabel(app.ShockTubePostProcessorUIFigure);
            app.inEditField_5Label.Text = 'in';
            app.inEditField_5 = uieditfield(app.ShockTubePostProcessorUIFigure, 'text');
            app.inEditField_5.Editable = 'off';
            app.inEditField_5.Value = '13.02756';
            app.inEditField_7Label = uilabel(app.ShockTubePostProcessorUIFigure);
            app.inEditField_7Label.Text = 'in';
            app.inEditField_7 = uieditfield(app.ShockTubePostProcessorUIFigure, 'text');
            app.inEditField_7.Editable = 'off';
            app.inEditField_7.Value = '0.6';
            app.Port4Label = uilabel(app.ShockTubePostProcessorUIFigure);
            app.Port4Label.Text = 'Port4';
            app.Port5Label = uilabel(app.ShockTubePostProcessorUIFigure);
            app.Port5Label.Text = 'Port5';
            app.Port6Label = uilabel(app.ShockTubePostProcessorUIFigure);
            app.Port6Label.Text = 'Port6';
            app.Port7Label = uilabel(app.ShockTubePostProcessorUIFigure);
            app.Port7Label.Text = 'Port7';
            app.Port8Label = uilabel(app.ShockTubePostProcessorUIFigure);
            app.Port8Label.Text = 'Port8';
            app.inEditField_6Label = uilabel(app.ShockTubePostProcessorUIFigure);
            app.inEditField_6Label.Text = 'in';
            app.inEditField_6 = uieditfield(app.ShockTubePostProcessorUIFigure, 'text');
            app.inEditField_6.Editable = 'off';
            app.inEditField_6.Value = '4.0';
            app.dateTextAreaLabel = uilabel(app.ShockTubePostProcessorUIFigure);
            app.dateTextAreaLabel.Text = 'date';
            app.dateTextArea = uitextarea(app.ShockTubePostProcessorUIFigure);
            app.dateTextArea.Value = {'20231205'};
            app.FROSHSolnButtonGroup = uibuttongroup(app.ShockTubePostProcessorUIFigure);
            app.FROSHSolnButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FROSHSolnButtonGroupSelectionChanged, true);
            app.FROSHSolnButtonGroup.Title = 'FROSH Soln';
            app.EEButton = uiradiobutton(app.FROSHSolnButtonGroup);
            app.EEButton.Text = 'EE';
            app.EEButton.Value = true;
            app.FEButton = uiradiobutton(app.FROSHSolnButtonGroup);
            app.FEButton.Text = 'FE';
            app.FFButton = uiradiobutton(app.FROSHSolnButtonGroup);
            app.FFButton.Text = 'FF';
            app.NASAFileTextAreaLabel = uilabel(app.ShockTubePostProcessorUIFigure);
            app.NASAFileTextAreaLabel.Text = 'NASA File';
            app.NASAFileTextArea = uitextarea(app.ShockTubePostProcessorUIFigure);
            app.NASAFileTextArea.Value = {'THERM.DAT'};
            app.State5AssumeButtonGroup = uibuttongroup(app.ShockTubePostProcessorUIFigure);
            app.State5AssumeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @State5AssumeButtonGroupSelectionChanged, true);
            app.State5AssumeButtonGroup.Title = 'State 5 Assume';
            app.isentropicButton = uiradiobutton(app.State5AssumeButtonGroup);
            app.isentropicButton.Text = 'isentropic';
            app.isentropicButton.Value = true;
            app.isochoricButton = uiradiobutton(app.State5AssumeButtonGroup);
            app.isochoricButton.Text = 'isochoric';
            app.isobaricButton = uiradiobutton(app.State5AssumeButtonGroup);
            app.isobaricButton.Text = 'isobaric';
            app.PlotCh0Button = uibutton(app.ShockTubePostProcessorUIFigure, 'push');
            app.PlotCh0Button.ButtonPushedFcn = createCallbackFcn(app, @PlotCh0ButtonPushed, true);
            app.PlotCh0Button.Text = 'Plot Ch0';
            app.PlotCh1Button = uibutton(app.ShockTubePostProcessorUIFigure, 'push');
            app.PlotCh1Button.ButtonPushedFcn = createCallbackFcn(app, @PlotCh1ButtonPushed, true);
            app.PlotCh1Button.Text = 'Plot Ch1';
            app.PlotCh2Button = uibutton(app.ShockTubePostProcessorUIFigure, 'push');
            app.PlotCh2Button.ButtonPushedFcn = createCallbackFcn(app, @PlotCh2ButtonPushed, true);
            app.PlotCh2Button.Text = 'Plot Ch2';
            app.PlotCh3Button = uibutton(app.ShockTubePostProcessorUIFigure, 'push');
            app.PlotCh3Button.ButtonPushedFcn = createCallbackFcn(app, @PlotCh3ButtonPushed, true);
            app.PlotCh3Button.Text = 'Plot Ch3';
            app.PlotCh4Button = uibutton(app.ShockTubePostProcessorUIFigure, 'push');
            app.PlotCh4Button.ButtonPushedFcn = createCallbackFcn(app, @PlotCh4ButtonPushed, true);
            app.PlotCh4Button.Text = 'Plot Ch4';
            app.PlotCh5Button = uibutton(app.ShockTubePostProcessorUIFigure, 'push');
            app.PlotCh5Button.ButtonPushedFcn = createCallbackFcn(app, @PlotCh5ButtonPushed, true);
            app.PlotCh5Button.Text = 'Plot Ch5';
            app.PlotCh6Button = uibutton(app.ShockTubePostProcessorUIFigure, 'push');
            app.PlotCh6Button.ButtonPushedFcn = createCallbackFcn(app, @PlotCh6ButtonPushed, true);
            app.PlotCh6Button.Text = 'Plot Ch6';
            app.PlotCh7Button = uibutton(app.ShockTubePostProcessorUIFigure, 'push');
            app.PlotCh7Button.ButtonPushedFcn = createCallbackFcn(app, @PlotCh7ButtonPushed, true);
            app.PlotCh7Button.Text = 'Plot Ch7';
            app.LoadRawDataPlotUisButton = uibutton(app.ShockTubePostProcessorUIFigure, 'push');
            app.LoadRawDataPlotUisButton.ButtonPushedFcn = createCallbackFcn(app, @LoadRawDataPlotUisButtonPushed, true);
            app.LoadRawDataPlotUisButton.Text = {'Load Raw Data'; 'Plot Uis'};
            app.T5KTextAreaLabel = uilabel(app.ShockTubePostProcessorUIFigure);
            app.T5KTextAreaLabel.Text = 'T5[K]';
            app.T5KTextArea = uitextarea(app.ShockTubePostProcessorUIFigure);
            app.T5KTextArea.Editable = 'off';
            app.P5PaTextAreaLabel = uilabel(app.ShockTubePostProcessorUIFigure);
            app.P5PaTextAreaLabel.Text = 'P5[Pa]';
            app.P5PaTextArea = uitextarea(app.ShockTubePostProcessorUIFigure);
            app.P5PaTextArea.Editable = 'off';
            app.UismsTextAreaLabel = uilabel(app.ShockTubePostProcessorUIFigure);
            app.UismsTextAreaLabel.Text = 'Uis [m/s]';
            app.UismsTextArea = uitextarea(app.ShockTubePostProcessorUIFigure);
            app.UismsTextArea.Editable = 'off';
            app.PCB7CheckBox = uicheckbox(app.ShockTubePostProcessorUIFigure);
            app.PCB7CheckBox.ValueChangedFcn = createCallbackFcn(app, @PCB7CheckBoxValueChanged, true);
            app.PCB7CheckBox.Text = '';
            app.PCB7CheckBox.Value = true;
            app.PCB6CheckBox = uicheckbox(app.ShockTubePostProcessorUIFigure);
            app.PCB6CheckBox.ValueChangedFcn = createCallbackFcn(app, @PCB6CheckBoxValueChanged, true);
            app.PCB6CheckBox.Text = '';
            app.PCB6CheckBox.Value = true;
            app.PCB5CheckBox = uicheckbox(app.ShockTubePostProcessorUIFigure);
            app.PCB5CheckBox.ValueChangedFcn = createCallbackFcn(app, @PCB5CheckBoxValueChanged, true);
            app.PCB5CheckBox.Text = '';
            app.PCB5CheckBox.Value = true;
            app.PCB4CheckBox = uicheckbox(app.ShockTubePostProcessorUIFigure);
            app.PCB4CheckBox.ValueChangedFcn = createCallbackFcn(app, @PCB4CheckBoxValueChanged, true);
            app.PCB4CheckBox.Text = '';
            app.PCB4CheckBox.Value = true;
            app.PCB3CheckBox = uicheckbox(app.ShockTubePostProcessorUIFigure);
            app.PCB3CheckBox.ValueChangedFcn = createCallbackFcn(app, @PCB3CheckBoxValueChanged, true);
            app.PCB3CheckBox.Text = '';
            app.PCB3CheckBox.Value = true;
            app.PCB2CheckBox = uicheckbox(app.ShockTubePostProcessorUIFigure);
            app.PCB2CheckBox.ValueChangedFcn = createCallbackFcn(app, @PCB2CheckBoxValueChanged, true);
            app.PCB2CheckBox.Text = '';
            app.PCB2CheckBox.Value = true;
            app.PCB2Label = uilabel(app.ShockTubePostProcessorUIFigure);
            app.PCB2Label.Text = 'PCB2';
            % Show the figure after all components are created
            app.ShockTubePostProcessorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ShockTubePostProcessorWithPlot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ShockTubePostProcessorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ShockTubePostProcessorUIFigure)
        end
    end
end